classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FileTab                         matlab.ui.container.Tab
        UIAxes_FileTab                  matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        EditField_ImageNumber_FileTab   matlab.ui.control.EditField
        ImageNumberSpinnerLabel         matlab.ui.control.Label
        ImageNumberSpinner_FileTab      matlab.ui.control.Spinner
        matButton                       matlab.ui.control.Button
        volButton                       matlab.ui.control.Button
        octbinButton                    matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        RotateButton                    matlab.ui.control.Button
        LoadExcelFileButton             matlab.ui.control.Button
        ManualLayerSegmentationTab      matlab.ui.container.Tab
        BoundariesPanel_2               matlab.ui.container.Panel
        ILMButton_ManualTab             matlab.ui.control.Button
        NFLGCLButton_ManualTab          matlab.ui.control.Button
        INLOPLButton_ManualTab          matlab.ui.control.Button
        OPLONLButton_ManualTab          matlab.ui.control.Button
        ONLISOSButton_ManualTab         matlab.ui.control.Button
        ISOSRPEButton_ManualTab         matlab.ui.control.Button
        BMChoroidButton_ManualTab       matlab.ui.control.Button
        IPLINLButton_ManualTab          matlab.ui.control.Button
        GCLIPLButton_ManualTab          matlab.ui.control.Button
        UIAxes_ManualTab                matlab.ui.control.UIAxes
        ImageNumberEditFieldLabel       matlab.ui.control.Label
        ImageNumberEditField_ManualTab  matlab.ui.control.NumericEditField
        ShowAllLayersandSaveButton_ManualTab  matlab.ui.control.Button
        AutoLayerSegmentationTab        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        SemiAutomaticTab                matlab.ui.container.Tab
        ImageNumberEditField_5Label     matlab.ui.control.Label
        ImageNumberEditField_SemiTab    matlab.ui.control.NumericEditField
        UIAxes_SemiTab                  matlab.ui.control.UIAxes
        ShowAllLayersandSaveButton_SemiTab  matlab.ui.control.Button
        BoundariesPanel                 matlab.ui.container.Panel
        ILMButton_SemiTab               matlab.ui.control.Button
        NFLGCLButton_SemiTab            matlab.ui.control.Button
        IPLINLButton_SemiTab            matlab.ui.control.Button
        GCLIPLButton_SemiTab            matlab.ui.control.Button
        INLOPLButton_SemiTab            matlab.ui.control.Button
        OPLONLButton_SemiTab            matlab.ui.control.Button
        ONLISOSButton_SemiTab           matlab.ui.control.Button
        ISOSRPEButton_SemiTab           matlab.ui.control.Button
        BMChoroidButton_SemiTab         matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp3                           matlab.ui.control.Lamp
        Lamp4                           matlab.ui.control.Lamp
        Lamp5                           matlab.ui.control.Lamp
        Lamp6                           matlab.ui.control.Lamp
        Lamp7                           matlab.ui.control.Lamp
        Lamp9                           matlab.ui.control.Lamp
        Lamp8                           matlab.ui.control.Lamp
        IrregularityIndexEditFieldLabel  matlab.ui.control.Label
        IrregularityIndexEditField_IPLSemiTab  matlab.ui.control.NumericEditField
        IrregularityIndexEditField_2Label  matlab.ui.control.Label
        IrregularityIndexEditField_OPLSemiTab  matlab.ui.control.NumericEditField
        SemiAutomaticLayerCorrectionTab  matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        ILMCheckBox_SemiCorrTab         matlab.ui.control.CheckBox
        NFLGCLCheckBox_SemiCorrTab      matlab.ui.control.CheckBox
        GCLIPLCheckBox_SemiCorrTab      matlab.ui.control.CheckBox
        IPLINLCheckBox_SemiCorrTab      matlab.ui.control.CheckBox
        INLOPLCheckBox_SemiCorrTab      matlab.ui.control.CheckBox
        OPLONLCheckBox_SemiCorrTab      matlab.ui.control.CheckBox
        ONLISOSCheckBox_SemiCorrTab     matlab.ui.control.CheckBox
        ISOSRPECheckBox_SemiCorrTab     matlab.ui.control.CheckBox
        BMChoroidCheckBox_SemiCorrTab   matlab.ui.control.CheckBox
        GridEditField_3Label            matlab.ui.control.Label
        GridEditField_SemiCorrTab       matlab.ui.control.NumericEditField
        ShowAllLayersandSaveButton_SemiCorrTab  matlab.ui.control.Button
        UIAxes_SemiCorrTab              matlab.ui.control.UIAxes
        ManualGridTab                   matlab.ui.container.Tab
        GridEditFieldLabel              matlab.ui.control.Label
        GridEditField_GridTab           matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        ILMCheckBox_GridTab             matlab.ui.control.CheckBox
        NFLGCLCheckBox_GridTab          matlab.ui.control.CheckBox
        GCLIPLCheckBox_GridTab          matlab.ui.control.CheckBox
        IPLINLCheckBox_GridTab          matlab.ui.control.CheckBox
        INLOPLCheckBox_GridTab          matlab.ui.control.CheckBox
        OPLONLCheckBox_GridTab          matlab.ui.control.CheckBox
        ONLISOSCheckBox_GridTab         matlab.ui.control.CheckBox
        ISOSRPECheckBox_GridTab         matlab.ui.control.CheckBox
        BMChoroidCheckBox_GridTab       matlab.ui.control.CheckBox
        ImageNumberEditField_2Label     matlab.ui.control.Label
        ImageNumberEditField_GridTab    matlab.ui.control.NumericEditField
        ShowAllLayersandSaveButton_GridTab  matlab.ui.control.Button
        UIAxes_GridTab                  matlab.ui.control.UIAxes
        ManualGridLayerCorrectionTab    matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        ILMCheckBox_GridCorrTab         matlab.ui.control.CheckBox
        NFLGCLCheckBox_GridCorrTab      matlab.ui.control.CheckBox
        GCLIPLCheckBox_GridCorrTab      matlab.ui.control.CheckBox
        IPLINLCheckBox_GridCorrTab      matlab.ui.control.CheckBox
        INLOPLCheckBox_GridCorrTab      matlab.ui.control.CheckBox
        OPLONLCheckBox_GridCorrTab      matlab.ui.control.CheckBox
        ONLISOSCheckBox_GridCorrTab     matlab.ui.control.CheckBox
        ISOSRPECheckBox_GridCorrTab     matlab.ui.control.CheckBox
        BMChoroidCheckBox_GridCorrTab   matlab.ui.control.CheckBox
        GridEditField_2Label            matlab.ui.control.Label
        GridEditField_GridCorrTab       matlab.ui.control.NumericEditField
        ShowAllLayersandSaveButton_GridCorrTab  matlab.ui.control.Button
        UIAxes_GridCorrTab              matlab.ui.control.UIAxes
        FluidSegmentationTab            matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        SemiAutomaticTab_2              matlab.ui.container.Tab
        UIAxes_SemiFluidTab             matlab.ui.control.UIAxes
        Panel_5                         matlab.ui.container.Panel
        IRFCheckBox_SemiFluidTab        matlab.ui.control.CheckBox
        SRFCheckBox_SemiFluidTab        matlab.ui.control.CheckBox
        PEDCheckBox_SemiFluidTab        matlab.ui.control.CheckBox
        DeleteButton_SemiFluidTab       matlab.ui.control.Button
        FinishButton_SemiFluidTab       matlab.ui.control.Button
        ImageNumberEditField_3Label     matlab.ui.control.Label
        ImageNumberEditField_SemiFluidTab  matlab.ui.control.NumericEditField
        NumberofObjectsEditFieldLabel   matlab.ui.control.Label
        NumberofObjectsEditField_SemiFluidTab  matlab.ui.control.NumericEditField
        ManualTab                       matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        IRFCheckBox_ManualFluidTab      matlab.ui.control.CheckBox
        SRFCheckBox_ManualFluidTab      matlab.ui.control.CheckBox
        PEDCheckBox_ManualFluidTab      matlab.ui.control.CheckBox
        DeleteButton_ManualFluidTab     matlab.ui.control.Button
        FinishButton_ManualFluidTab     matlab.ui.control.Button
        ImageNumberEditField_4Label     matlab.ui.control.Label
        ImageNumberEditField_ManualFluidTab  matlab.ui.control.NumericEditField
        NumberofObjectsEditField_2Label  matlab.ui.control.Label
        NumberofObjectsEditField_ManualFluidTab  matlab.ui.control.NumericEditField
        UIAxes_ManualFluidTab           matlab.ui.control.UIAxes
        PeripapillaryTab                matlab.ui.container.Tab
        UIAxes_Peri                     matlab.ui.control.UIAxes
        Panel_7                         matlab.ui.container.Panel
        Boundary6Button_Peri            matlab.ui.control.Button
        Boundary3Button_Peri            matlab.ui.control.Button
        VeinDetectionButton_Peri        matlab.ui.control.Button
        Boundary2Button_Peri            matlab.ui.control.Button
        Boundary4Button_Peri            matlab.ui.control.Button
        Boundary3_finalButton_Peri      matlab.ui.control.Button
        Boundary5Button_Peri            matlab.ui.control.Button
        Boundary7Button_Peri            matlab.ui.control.Button
        Boundary8Button_Peri            matlab.ui.control.Button
        Boundary1Button_Peri            matlab.ui.control.Button
        LoadButton_Peri                 matlab.ui.control.Button
        ShowAllLayersandSaveButton_Peri  matlab.ui.control.Button
    end


    properties (Access = public)
       n=1% Description
       p=1
       g=10
       g1=10
       g2=10
       I
       M
       N
       K
       contourxn1
       contouryn1
       contourxn2
       contouryn2
       contourxn3
       contouryn3
       contourxn4
       contouryn4
       contourxn5
       contouryn5
       contourxn6
       contouryn6
       contourxn7
       contouryn7
       contourxn8
       contouryn8
       contourxn9
       contouryn9
       contourxn1_grid
       contouryn1_grid
       contourxn2_grid
       contouryn2_grid
       contourxn3_grid
       contouryn3_grid
       contourxn4_grid
       contouryn4_grid
       contourxn5_grid
       contouryn5_grid
       contourxn6_grid
       contouryn6_grid
       contourxn7_grid
       contouryn7_grid
       contourxn8_grid
       contouryn8_grid
       contourxn9_grid
       contouryn9_grid
       contourx_final
       del1=0;
       del2=0
       ii=0
       f
       ff
       n1=1
       n2=1
       n3=1
       n4=1
       Imb
       w 
       z
       IRFp
       SRFp
       InterRFp
       PEDp
       IRFmp
       SRFmp
       InterRFmp
       PEDmp
       d
       Ig
       Ig1
       Ig2
       Ig3
       pcontourxn1
       pcontourxn2
       pcontourxn3
       pcontourxn4
       pcontourxn5
       pcontourxn6
       pcontourxn7
       pcontourxn8
       u=0
       Phi
       r=0
       y1
       y2
       y3
       y4
       y5
       y6
       y7
       y8
       y9
       contoury_final
       file
       num
       time_manual
       time_semi
       time_grid
       
       index_IPL_semi=0
       index_OPL_semi=0
       index_IPL_grid=0
       index_OPL_grid=0

       
    end

    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Value changed function: ImageNumberSpinner_FileTab
        function ImageNumberSpinner_FileTabValueChanged(app, event)
            value = app.ImageNumberSpinner_FileTab.Value;
            app.n=value;
            app.UIAxes_FileTab.XAxis.TickLabels={};
%             app.UIAxes_FileTab.YAxis.TickLabels={};
            imshow(app.I(:,:,app.n),'Parent',app.UIAxes_FileTab);
            % app.UIAxes_FileTab.XLim=[0 app.I.XData(2)];
            % app.UIAxes_FileTab.YLim=[0 app.I.YData(2)];
        end

        % Value changed function: ImageNumberEditField_SemiTab
        function ImageNumberEditField_SemiTabValueChanged(app, event)
            value = app.ImageNumberEditField_SemiTab.Value;
            app.ImageNumberEditField_SemiTab.Limits = [0 app.K];
            app.p=value;
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab);
            app.contourxn1=NaN(app.N,1);
            app.contourxn2=NaN(app.N,1);
            app.contourxn3=NaN(app.N,1);
            app.contourxn4=NaN(app.N,1);
            app.contourxn5=NaN(app.N,1);
            app.contourxn6=NaN(app.N,1);
            app.contourxn7=NaN(app.N,1);
            app.contourxn8=NaN(app.N,1);
            app.contourxn9=NaN(app.N,1);
            app.Lamp.Color  = [1 0 0];
            app.Lamp2.Color = [1 0 0];
            app.Lamp3.Color = [1 0 0];
            app.Lamp4.Color = [1 0 0];
            app.Lamp5.Color = [1 0 0];
            app.Lamp6.Color = [1 0 0];
            app.Lamp7.Color = [1 0 0];
            app.Lamp8.Color = [1 0 0];
            app.Lamp9.Color = [1 0 0];
            app.time_semi=NaN(1,9);
            
%           app.IrregularityIndexEditField_IPLSemiCorrTab.Value=0;
        %   app.IrregularityIndexEditField_OPLSemiCorrTab.Value=0;
           app.IrregularityIndexEditField_IPLSemiTab.Value=0;
           app.IrregularityIndexEditField_OPLSemiTab.Value=0;
        end

        % Button pushed function: ShowAllLayersandSaveButton_SemiTab
        function ShowAllLayersandSaveButton_SemiTabPushed(app, event)
            app.contourx_final=[app.contourxn1';app.contourxn2';app.contourxn3';app.contourxn4';app.contourxn5';app.contourxn6';app.contourxn7';app.contourxn8';app.contourxn9'];
            hold(app.UIAxes_SemiTab, 'on');
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
            for k=1:8 
             for j=1:app.N
                 if app.contourx_final(k,j)>app.contourx_final(k+1,j) || app.contourx_final(k,j)==app.contourx_final(k+1,j)
                    app.contourx_final(k+1,j)=app.contourx_final(k,j)+1;
                end
             end
            end

            plot(app.UIAxes_SemiTab,1:app.N,app.contourx_final(1,:),'g','LineWidth',0.5);
            plot(app.UIAxes_SemiTab,1:app.N,app.contourx_final(2,:),'y','LineWidth',0.5);
            plot(app.UIAxes_SemiTab,1:app.N,app.contourx_final(3,:),'b','LineWidth',0.5);
            plot(app.UIAxes_SemiTab,1:app.N,app.contourx_final(4,:),'m','LineWidth',0.5);
            plot(app.UIAxes_SemiTab,1:app.N,app.contourx_final(5,:),'r','LineWidth',0.5);
            plot(app.UIAxes_SemiTab,1:app.N,app.contourx_final(6,:),'g','LineWidth',0.5);
            plot(app.UIAxes_SemiTab,1:app.N,app.contourx_final(7,:),'color','red','LineWidth',0.5);
            plot(app.UIAxes_SemiTab,1:app.N,app.contourx_final(8,:),'color','y','LineWidth',0.5);
            plot(app.UIAxes_SemiTab,1:app.N,app.contourx_final(9,:),'c','LineWidth',0.5); 
            
            finalLayer_semiauto= app.contourx_final;
            image=app.I(:,:,app.p);
            Time=app.time_semi;
            
%             if ~isnan(app.contourx_final(4,:))
%             app.index_IPL_semi=irregularity_index(app.contourx_final(4,:));
%             end
%             if ~isnan(app.contourx_final(6,:))
%             app.index_OPL_semi=irregularity_index(app.contourx_final(6,:));
%             end
           index=[app.index_IPL_semi;app.index_OPL_semi];
            
%            uisave({'finalLayer_semiauto','image'},'layerinfo_semiauto.mat');
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name1=strcat(name,'/Alllayers_info.mat');
            name2=strcat(name,'/image.mat');
            name3=strcat(name,'/Time.mat');
            name4=strcat(name,'/Irregularity Index.mat');
            save(name1, 'finalLayer_semiauto')
            save(name2, 'image')
            save(name3, 'Time')
            save(name4, 'index')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes_SemiTab,fig);
            axis off
            saveas(fig, fullfile(name, 'final image with layers'), 'jpg');
            delete(fig); 
            
            app.IrregularityIndexEditField_IPLSemiTab.Value=app.index_IPL_semi;
            app.IrregularityIndexEditField_OPLSemiTab.Value=app.index_OPL_semi;
        end

        % Value changed function: ILMCheckBox_GridTab
        function ILMCheckBox_GridTabValueChanged(app, event)
            value = app.ILMCheckBox_GridTab.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            tic
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            delete(gcf)
            [app.contouryn1_grid,app.contourxn1_grid]=resize_contour(a,b,50,app.I(:,:,app.p));
            T=toc;
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridTab)
            hold(app.UIAxes_GridTab, 'on')
            plot(app.UIAxes_GridTab,app.contouryn1_grid,app.contourxn1_grid,'color','[0.6 1 0.2]');
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ILM.mat');
            layer1_grid = app.contourxn1_grid;
            save(name, 'layer1_grid')
            app.time_grid(1,1)=T;
            end
        end

        % Value changed function: NFLGCLCheckBox_GridTab
        function NFLGCLCheckBox_GridTabValueChanged(app, event)
            value = app.NFLGCLCheckBox_GridTab.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            tic
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            delete(gcf)
            [app.contouryn2_grid,app.contourxn2_grid]=resize_contour(a,b,50,app.I(:,:,app.p));
            T=toc;
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridTab)
            hold(app.UIAxes_GridTab, 'on')
            plot(app.UIAxes_GridTab,app.contouryn2_grid,app.contourxn2_grid,'color','[0.6 1 0.2]');
            
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/NFL-GCL.mat');
            layer2_grid = app.contourxn2_grid;
            save(name, 'layer2_grid')
            app.time_grid(1,2)=T;
            end
        end

        % Value changed function: GCLIPLCheckBox_GridTab
        function GCLIPLCheckBox_GridTabValueChanged(app, event)
            value = app.GCLIPLCheckBox_GridTab.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            tic
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            delete(gcf)
            [app.contouryn3_grid,app.contourxn3_grid]=resize_contour(a,b,50,app.I(:,:,app.p));
            T=toc;
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridTab)
            hold(app.UIAxes_GridTab, 'on')
            plot(app.UIAxes_GridTab,app.contouryn3_grid,app.contourxn3_grid,'color','[0.6 1 0.2]');
            
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/GCL-IPL.mat');
            layer3_grid = app.contourxn3_grid;
            save(name, 'layer3_grid')
            app.time_grid(1,3)=T;
            end
        end

        % Value changed function: IPLINLCheckBox_GridTab
        function IPLINLCheckBox_GridTabValueChanged(app, event)
            value = app.IPLINLCheckBox_GridTab.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            tic
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'spline');
            delete(gcf)
            
            [app.contouryn4_grid,app.contourxn4_grid]=resize_contour(a,b,50,app.I(:,:,app.p));
            
            T=toc;
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridTab)
            hold(app.UIAxes_GridTab, 'on')
            plot(app.UIAxes_GridTab,app.contouryn4_grid,app.contourxn4_grid,'color','[0.6 1 0.2]');
            
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/IPL-INL.mat');
            layer4_grid= app.contourxn4_grid;
            save(name, 'layer4_grid')
            app.time_grid(1,4)=T;
            end
        end

        % Value changed function: INLOPLCheckBox_GridTab
        function INLOPLCheckBox_GridTabValueChanged(app, event)
            value = app.INLOPLCheckBox_GridTab.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            tic
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            delete(gcf)
            [app.contouryn5_grid,app.contourxn5_grid]=resize_contour(a,b,50,app.I(:,:,app.p));
            T=toc;
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridTab)
            hold(app.UIAxes_GridTab, 'on')
            plot(app.UIAxes_GridTab,app.contouryn5_grid,app.contourxn5_grid,'color','[0.6 1 0.2]');
            
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/INL-OPL.mat');
            layer5_grid = app.contourxn5_grid;
            save(name, 'layer5_grid')
            app.time_grid(1,5)=T;
            end
        end

        % Value changed function: OPLONLCheckBox_GridTab
        function OPLONLCheckBox_GridTabValueChanged(app, event)
            
            value = app.OPLONLCheckBox_GridTab.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            tic
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear','extrap');
            delete(gcf)
            app.index_OPL_grid=irregularity_index(b);
            [app.contouryn6_grid,app.contourxn6_grid]=resize_contour(a,b,50,app.I(:,:,app.p));
            T=toc;
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridTab)
            hold(app.UIAxes_GridTab, 'on')
            plot(app.UIAxes_GridTab,app.contouryn6_grid,app.contourxn6_grid,'color','[0.6 1 0.2]');
            
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/OPL-ONL.mat');
            layer6_grid = app.contourxn6_grid;
            save(name, 'layer6_grid')
            app.time_grid(1,6)=T;
            end
        end

        % Value changed function: ONLISOSCheckBox_GridTab
        function ONLISOSCheckBox_GridTabValueChanged(app, event)
            value = app.ONLISOSCheckBox_GridTab.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            tic
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            delete(gcf)
            [app.contouryn7_grid,app.contourxn7_grid]=resize_contour(a,b,50,app.I(:,:,app.p));
            T=toc;
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridTab)
            hold(app.UIAxes_GridTab, 'on')
            plot(app.UIAxes_GridTab,app.contouryn7_grid,app.contourxn7_grid,'color','[0.6 1 0.2]');
            
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ONL-ISOS.mat');
            layer7_grid = app.contourxn7_grid;
            save(name, 'layer7_grid')
            app.time_grid(1,7)=T;
            end
        end

        % Value changed function: ISOSRPECheckBox_GridTab
        function ISOSRPECheckBox_GridTabValueChanged(app, event)
            value = app.ISOSRPECheckBox_GridTab.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            tic
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            delete(gcf)
            [app.contouryn8_grid,app.contourxn8_grid]=resize_contour(a,b,50,app.I(:,:,app.p));
            T=toc;
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridTab)
            hold(app.UIAxes_GridTab, 'on')
            plot(app.UIAxes_GridTab,app.contouryn8_grid,app.contourxn8_grid,'color','[0.6 1 0.2]');
            
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ISOS-RPE.mat');
            layer8_grid = app.contourxn8_grid;
            save(name, 'layer8_grid')
            app.time_grid(1,8)=T;
            end
        end

        % Value changed function: BMChoroidCheckBox_GridTab
        function BMChoroidCheckBox_GridTabValueChanged(app, event)
            value = app.BMChoroidCheckBox_GridTab.Value;
            if value==true
            imshow(app.I(:,:,app.p))
            for c=1:floor(app.N/app.g):app.N
            line([c,c],[1,app.M],'color','red');
            end
            x=zeros(size(app.g+1));
            y=zeros(size(app.g+1));
            tic
            for i=1:app.g+1
            [x(i),y(i)]=ginputc(1,'color','y');
            hold on
            plot(x(i),y(i),'cyanx')
            end
            a=1:1:app.N;
            b=interp1(x,y,a,'linear');
            delete(gcf)
            [app.contouryn9_grid,app.contourxn9_grid]=resize_contour(a,b,50,app.I(:,:,app.p));
            T=toc;
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridTab)
            hold(app.UIAxes_GridTab, 'on')
            plot(app.UIAxes_GridTab,app.contouryn9_grid,app.contourxn9_grid,'color','[0.6 1 0.2]');
            
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/BM-Choroid.mat');
            layer9_grid = app.contourxn9_grid;
            save(name, 'layer9_grid')
            app.time_grid(1,9)=T;
            end
        
        end

        % Value changed function: GridEditField_GridTab
        function GridEditField_GridTabValueChanged(app, event)
            value = app.GridEditField_GridTab.Value;
            app.g=value;
        end

        % Value changed function: GridEditField_GridCorrTab
        function GridEditField_GridCorrTabValueChanged(app, event)
            value = app.GridEditField_GridCorrTab.Value;
            app.g2=value;
        end

        % Value changed function: ILMCheckBox_GridCorrTab
        function ILMCheckBox_GridCorrTabValueChanged(app, event)
            value = app.ILMCheckBox_GridCorrTab.Value;
            if value==true
            [app.contourxn1_grid,app.contouryn1_grid]=manual_correction(app.I(:,:,app.p),app.contourxn1_grid,app.contouryn1_grid,app.g2);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridCorrTab)
            hold(app.UIAxes_GridCorrTab, 'on')
            plot(app.UIAxes_GridCorrTab,app.contouryn1_grid,app.contourxn1_grid,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ILM.mat');
            layer1_grid = app.contourxn1_grid;
            save(name, 'layer1_grid')
        end

        % Value changed function: NFLGCLCheckBox_GridCorrTab
        function NFLGCLCheckBox_GridCorrTabValueChanged(app, event)
            value = app.NFLGCLCheckBox_GridCorrTab.Value;
            if value==true
            [app.contourxn2_grid,app.contouryn2_grid]=manual_correction(app.I(:,:,app.p),app.contourxn2_grid,app.contouryn2_grid,app.g2);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridCorrTab)
            hold(app.UIAxes_GridCorrTab, 'on')
            plot(app.UIAxes_GridCorrTab,app.contouryn2_grid,app.contourxn2_grid,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/NFL-GCL.mat');
            layer2_grid = app.contourxn2_grid;
            save(name, 'layer2_grid')
        end

        % Value changed function: GCLIPLCheckBox_GridCorrTab
        function GCLIPLCheckBox_GridCorrTabValueChanged(app, event)
            value = app.GCLIPLCheckBox_GridCorrTab.Value;
            if value==true
            [app.contourxn3_grid,app.contouryn3_grid]=manual_correction(app.I(:,:,app.p),app.contourxn3_grid,app.contouryn3_grid,app.g2);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridCorrTab)
            hold(app.UIAxes_GridCorrTab, 'on')
            plot(app.UIAxes_GridCorrTab,app.contouryn3_grid,app.contourxn3_grid,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/GCL-IPL.mat');
            layer3_grid = app.contourxn3_grid;
            save(name, 'layer3_grid')
        end

        % Value changed function: IPLINLCheckBox_GridCorrTab
        function IPLINLCheckBox_GridCorrTabValueChanged(app, event)
            value = app.IPLINLCheckBox_GridCorrTab.Value;
            if value==true
            [app.contourxn4_grid,app.contouryn4_grid]=manual_correction(app.I(:,:,app.p),app.contourxn4_grid,app.contouryn4_grid,app.g2);
            delete(gcf)
            app.index_IPL_grid=irregularity_index(app.contourxn4_grid);
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridCorrTab)
            hold(app.UIAxes_GridCorrTab, 'on')
            plot(app.UIAxes_GridCorrTab,app.contouryn4_grid,app.contourxn4_grid,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/IPL-INL.mat');
            layer4_grid = app.contourxn4_grid;
            save(name, 'layer4_grid')
        end

        % Value changed function: INLOPLCheckBox_GridCorrTab
        function INLOPLCheckBox_GridCorrTabValueChanged(app, event)
            value = app.INLOPLCheckBox_GridCorrTab.Value;
            if value==true
            [app.contourxn5_grid,app.contouryn5_grid]=manual_correction(app.I(:,:,app.p),app.contourxn5_grid,app.contouryn5_grid,app.g2);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridCorrTab)
            hold(app.UIAxes_GridCorrTab, 'on')
            plot(app.UIAxes_GridCorrTab,app.contouryn5_grid,app.contourxn5_grid,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/INL-OPL.mat');
            layer5_grid = app.contourxn5_grid;
            save(name, 'layer5_grid')
        end

        % Value changed function: OPLONLCheckBox_GridCorrTab
        function OPLONLCheckBox_GridCorrTabValueChanged(app, event)
            value = app.OPLONLCheckBox_GridCorrTab.Value;
            if value==true
            [app.contourxn6_grid,app.contouryn6_grid]=manual_correction(app.I(:,:,app.p),app.contourxn6_grid,app.contouryn6_grid,app.g2);
            delete(gcf)
            app.index_OPL_grid=irregularity_index(app.contourxn6_grid);
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridCorrTab)
            hold(app.UIAxes_GridCorrTab, 'on')
            plot(app.UIAxes_GridCorrTab,app.contouryn6_grid,app.contourxn6_grid,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/OPL-ONL.mat');
            layer6_grid = app.contourxn6_grid;
            save(name, 'layer6_grid')
        end

        % Value changed function: ONLISOSCheckBox_GridCorrTab
        function ONLISOSCheckBox_GridCorrTabValueChanged(app, event)
            value = app.ONLISOSCheckBox_GridCorrTab.Value;
            if value==true
            [app.contourxn7_grid,app.contouryn7_grid]=manual_correction(app.I(:,:,app.p),app.contourxn7_grid,app.contouryn7_grid,app.g2);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridCorrTab)
            hold(app.UIAxes_GridCorrTab, 'on')
            plot(app.UIAxes_GridCorrTab,app.contouryn7_grid,app.contourxn7_grid,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ONL-ISOS.mat');
            layer7_grid = app.contourxn7_grid;
            save(name, 'layer7_grid')
        end

        % Value changed function: ISOSRPECheckBox_GridCorrTab
        function ISOSRPECheckBox_GridCorrTabValueChanged(app, event)
           value = app.ISOSRPECheckBox_GridCorrTab.Value;
            if value==true
            [app.contourxn8_grid,app.contouryn8_grid]=manual_correction(app.I(:,:,app.p),app.contourxn8_grid,app.contouryn8_grid,app.g2);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridCorrTab)
            hold(app.UIAxes_GridCorrTab, 'on')
            plot(app.UIAxes_GridCorrTab,app.contouryn8_grid,app.contourxn8_grid,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ISOS-RPE.mat');
            layer8_grid = app.contourxn8_grid;
            save(name, 'layer8_grid')
        end

        % Value changed function: BMChoroidCheckBox_GridCorrTab
        function BMChoroidCheckBox_GridCorrTabValueChanged(app, event)
            value = app.BMChoroidCheckBox_GridCorrTab.Value;
            if value==true
            [app.contourxn9_grid,app.contouryn9_grid]=manual_correction(app.I(:,:,app.p),app.contourxn9_grid,app.contouryn9_grid,app.g2);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_GridCorrTab)
            hold(app.UIAxes_GridCorrTab, 'on')
            plot(app.UIAxes_GridCorrTab,app.contouryn9_grid,app.contourxn9_grid,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/BM-Choroid.mat');
            layer9_grid = app.contourxn9_grid;
            save(name, 'layer9_grid')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: EditField_ImageNumber_FileTab
        function EditField_ImageNumber_FileTabValueChanged(app, event)
            value = app.EditField_ImageNumber_FileTab.Value;
            app.EditField_ImageNumber_FileTab.Value = strcat('/ ',num2str(app.K));
        end

        % Button pushed function: matButton
        function matButtonPushed(app, event)
                 s=uiimport('-file');
                 s=struct2cell(s);
                 app.I=s{1};
                 app.I=uint8(app.I);
                 [app.M,app.N,app.K]=size(app.I);
                 imshow(app.I(:,:,1),'Parent',app.UIAxes_FileTab);
                 EditField_ImageNumber_FileTabValueChanged(app, event);
                 app.ImageNumberSpinner_FileTab.Value=1;
                 if app.K==1
                    app.ImageNumberSpinner_FileTab.Limits = [0 app.K];
                 else
                    app.ImageNumberSpinner_FileTab.Limits = [1 app.K];
                 end
        end

        % Button pushed function: volButton
        function volButtonPushed(app, event)
                  [file,path]=uigetfile('.vol');
                  address=strcat(path,file);
                  [NumBScans,Im_SLO_Image,OCTs,header,BScanHeader]= read_VOL_func(address);
                  app.I=OCTs;
                  app.I=(app.I);
                  [app.M,app.N,app.K]=size(app.I);
                  imshow(app.I(:,:,1),'Parent',app.UIAxes_FileTab);
                  EditField_ImageNumber_FileTabValueChanged(app, event);
                  app.ImageNumberSpinner_FileTab.Value=1;
                  if app.K==1
                    app.ImageNumberSpinner_FileTab.Limits = [0 app.K];
                 else
                    app.ImageNumberSpinner_FileTab.Limits = [1 app.K];
                 end
        end

        % Button pushed function: octbinButton
        function octbinButtonPushed(app, event)
              [file,path]=uigetfile('.octbin');
              address=strcat(path,file);
              [s] = readbin(address);
              a=struct2cell(s);
              b=struct2cell(a{2,1});
              c=struct2cell(b{2,1});
              d=struct2cell(c{2,1});
              e=d{1,1};
              for i=1:numel(e)
                  d=struct2cell(e{1,i});
                  Im=d{2,1};
                  OCTs(:,:,i)=Im;
              end
         
              app.I=OCTs;
              app.I=uint8(app.I);
              [app.M,app.N,app.K]=size(app.I); 
              imshow(app.I(:,:,1),'Parent',app.UIAxes_FileTab);
              EditField_ImageNumber_FileTabValueChanged(app, event);
              app.ImageNumberSpinner_FileTab.Value=1;
              if app.K==1
                    app.ImageNumberSpinner_FileTab.Limits = [0 app.K];
              else
                    app.ImageNumberSpinner_FileTab.Limits = [1 app.K];
              end
        end

        % Button pushed function: ILMButton_SemiTab
        function ILMButton_SemiTabPushed(app, event)
                    %1.live wire on layer1
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    hold(app.UIAxes_SemiTab, 'on');
                    [r_contour1,contoury1,contourx1,T]=lwcontour_time(Gy,app.I(:,:,app.p),1,1000);
                    [app.contouryn1,app.contourxn1]=resize_contour(contoury1,contourx1,app.num(1,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
                    plot(app.UIAxes_SemiTab,app.contouryn1,app.contourxn1,'color','[0 1 0.8]','linewidth',1);
                    app.Lamp.Color = 'green';
                  %  app.Lamp2.Color = [1 0.7843 0];
                    %2.live wire on layer1
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/ILM.mat');
                    layer1_auto = app.contourxn1;
                    save(name, 'layer1_auto')
                    app.time_semi(1,1)=T;
        end

        % Button pushed function: NFLGCLButton_SemiTab
        function NFLGCLButton_SemiTabPushed(app, event)
                    % 1.live wire on layer2(Dir)
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    [mag,dir]=imgradient(Gx,Gy);
                    se=strel('line',app.num(2,3),app.num(2,4));
                    dir_er=imerode(dir,se);
                    hold(app.UIAxes_SemiTab, 'on');
                    [r_contour2,contoury2,contourx2,T]=lwcontour_time(dir_er,app.I(:,:,app.p),1,1000);
                    [app.contouryn2,app.contourxn2]=resize_contour(contoury2,contourx2,app.num(2,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
                    plot(app.UIAxes_SemiTab,app.contouryn2,app.contourxn2,'y');
                    app.Lamp2.Color = 'green';
                    %app.Lamp3.Color = [1 0.7843 0];
                    %2.live wire on layer2(Dir)  
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/NFL-GCL.mat');
                    layer2_auto = app.contourxn2;
                    save(name, 'layer2_auto')
                    app.time_semi(1,2)=T;
        end

        % Button pushed function: IPLINLButton_SemiTab
        function IPLINLButton_SemiTabPushed(app, event)
                %1.live wire on layer4
                [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                [mag,dir]=imgradient(Gx,Gy);
                se=strel('rectangle',[app.num(4,3) app.num(4,4)]); 
                dir_er=imerode(dir,se);
                hold(app.UIAxes_SemiTab, 'on');
                [r_contour4,contoury4,contourx4,T]=lwcontour_time(dir_er,app.I(:,:,app.p),1,1000);
                
                app.index_IPL_semi=irregularity_index(contourx4);


                [app.contouryn4,app.contourxn4]=resize_contour(contoury4,contourx4,app.num(4,1),app.I(:,:,app.p));
                imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
                plot(app.UIAxes_SemiTab,app.contouryn4,app.contourxn4,'color','[1 0.4 0.6]' );
                app.Lamp3.Color = 'green';
                %app.Lamp4.Color = [1 0.7843 0];
                %2.live wire on layer4
                name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                mkdir(name)
                name=strcat(name,'/IPL-INL.mat');
                layer4_auto = app.contourxn4;
                save(name, 'layer4_auto')
                app.time_semi(1,4)=T;
        end

        % Button pushed function: GCLIPLButton_SemiTab
        function GCLIPLButton_SemiTabPushed(app, event)
                    %1.automatic layer3 finding
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    app.contourxn3=app.contourxn2;
                    for i=1:app.N
                    v=Gy(app.contourxn2(i):app.contourxn4(i),i);
                    r=find(v==max(v));   
                    if isempty(r)
                        app.contourxn3(i)=app.contourxn2(i);
                    else
                    app.contourxn3(i)=app.contourxn2(i)+max(r)-1; 
                    end
                    end
                    [app.contouryn3,app.contourxn3]=resize_contour(1:app.N,app.contourxn3,app.num(3,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
                    plot(app.UIAxes_SemiTab,app.contouryn3,app.contourxn3,'g');
                    app.Lamp4.Color = 'green';
                  %  app.Lamp5.Color = [1 0.7843 0];
                    %2.automatic layer3 finding
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/GCL-IPL.mat');
                    layer3_auto = app.contourxn3;
                    save(name, 'layer3_auto')
                  %  app.time_semi(1,3)=T;
        end

        % Button pushed function: INLOPLButton_SemiTab
        function INLOPLButton_SemiTabPushed(app, event)
                   %1.live wire on layer5
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    [mag,dir]=imgradient(Gx,Gy);
                    se=strel('rectangle',[app.num(5,3) app.num(5,4)]);
                    dir_di=imdilate(dir,se);
                    hold(app.UIAxes_SemiTab, 'on');
                    [r_contour5,contoury5,contourx5,T]=lwcontour_time(dir_di,app.I(:,:,app.p),1,1000);
                    [app.contouryn5,app.contourxn5]=resize_contour(contoury5,contourx5,app.num(5,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
                    plot(app.UIAxes_SemiTab,app.contouryn5,app.contourxn5,'color','[0.6 1 0.2]');
                    app.Lamp5.Color = 'green';
                    %app.Lamp6.Color = [1 0.7843 0];
                    %2.live wire on layer5
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/INL-OPL.mat');
                    layer5_auto = app.contourxn5;
                    save(name, 'layer5_auto')
                    app.time_semi(1,5)=T;
        end

        % Button pushed function: OPLONLButton_SemiTab
        function OPLONLButton_SemiTabPushed(app, event)
                    %1.live wire on layer6
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    [mag,dir]=imgradient(Gx,Gy);
                    se=strel('line',app.num(6,3),app.num(6,4));
                    dir_er=imerode(dir,se);
                    hold(app.UIAxes_SemiTab, 'on');
                    [r_contour6,contoury6,contourx6,T]=lwcontour_time(dir_er,app.I(:,:,app.p),1,1000); 
                    
                    app.index_OPL_semi=irregularity_index(contourx6);
                    
                    [app.contouryn6,app.contourxn6]=resize_contour(contoury6,contourx6,app.num(6,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
                    plot(app.UIAxes_SemiTab,app.contouryn6,app.contourxn6,'m');
                    app.Lamp6.Color = 'green';
                   % app.Lamp7.Color = [1 0.7843 0];
                    %2.live wire on layer6
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/OPL-ONL.mat');
                    layer6_auto = app.contourxn6;
                    save(name, 'layer6_auto')
                    app.time_semi(1,6)=T;
        end

        % Button pushed function: ONLISOSButton_SemiTab
        function ONLISOSButton_SemiTabPushed(app, event)
                    %1.live wire on layer7
                    I_canny=edge(app.I(:,:,app.p),'canny',app.num(7,6),app.num(7,7));
                    I_canny=~I_canny;
                    se=strel('line',app.num(7,3),app.num(7,4));
                    I_canny_di=imerode(I_canny,se);
                    hold(app.UIAxes_SemiTab, 'on');
                    [r_contour7,contoury7,contourx7,T] = lwcontour_time(I_canny_di,app.I(:,:,app.p),1,500);
                    [app.contouryn7,app.contourxn7]=resize_contour(contoury7,contourx7,app.num(7,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
                    plot(app.UIAxes_SemiTab,app.contouryn7,app.contourxn7,'color','g');
                    app.Lamp7.Color = 'green';
                    %app.Lamp8.Color = [1 0.7843 0];
                    %2.live wire on layer7
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/ONL-ISOS.mat');
                    layer7_auto = app.contourxn7;
                    save(name, 'layer7_auto')
                    app.time_semi(1,7)=T;
        end

        % Button pushed function: ISOSRPEButton_SemiTab
        function ISOSRPEButton_SemiTabPushed(app, event)
                    %1.live wire on layer8
                    [Gx,Gy] = imgradientxy(app.I(:,:,app.p),'sobel');
                    [mag,dir]=imgradient(Gx,Gy);
                    dirblack=black(round(app.contourxn7'),app.contouryn7,dir);
                    hold(app.UIAxes_SemiTab, 'on');
                    [r_contour8,contoury8,contourx8,T]=lwcontour_time(dirblack,app.I(:,:,app.p),1,500);
                    [app.contouryn8,app.contourxn8]=resize_contour(contoury8,contourx8,app.num(8,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
                    plot(app.UIAxes_SemiTab,app.contouryn8,app.contourxn8,'color','red');
                    app.Lamp8.Color = 'green';
                    %app.Lamp9.Color = [1 0.7843 0];
                    %2.live wire on layer8
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/ISOS-RPE.mat');
                    layer8_auto = app.contourxn8;
                    save(name, 'layer8_auto')
                    app.time_semi(1,8)=T;
        end

        % Button pushed function: BMChoroidButton_SemiTab
        function BMChoroidButton_SemiTabPushed(app, event)
                    %1.live wire on layer9
                    I_black=black412(round(app.contourxn7'),app.contouryn7,app.I(:,:,app.p));
                    hold(app.UIAxes_SemiTab, 'on');
                    [r_contour9,contoury9,contourx9,T] = lwcontour_time(I_black,app.I(:,:,app.p),1,500);
                    [app.contouryn9,app.contourxn9]=resize_contour(contoury9,contourx9,app.num(9,1),app.I(:,:,app.p));
                    imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiTab)
                    plot(app.UIAxes_SemiTab,app.contouryn9,app.contourxn9,'c');
                    app.Lamp9.Color = 'green';
                    %2.live wire on layer9
                    name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
                    mkdir(name)
                    name=strcat(name,'/BM-Choroid.mat');
                    layer9_auto = app.contourxn9;
                    save(name, 'layer9_auto')
                    app.time_semi(1,9)=T;
        end

        % Value changed function: IRFCheckBox_ManualFluidTab
        function IRFCheckBox_ManualFluidTabValueChanged(app, event)
            value = app.IRFCheckBox_ManualFluidTab.Value;
            if value==true
                app.IRFmp=[];
                app.ii=0;
                app.f=0;
                app.NumberofObjectsEditField_ManualFluidTab.Value=0;
                imshow(app.I(:,:,app.n2)); 
                app.Imb=imbinarize(app.I(:,:,app.n2));
                t=app.Imb.*0;
                while (1)                
                   app.ii=app.ii+1; 
                   h(app.ii)=imfreehand(gca,'closed',false);
                   h(app.ii).Deletable=false;
                   if app.del1==1
                        app.ii=app.ii-1;
                        h(app.ii).Deletable=true;
                        delete(h(app.ii));
                        delete(app.ff)  
                        
                        t=t-mask ;
                       % app.numberofobjectsEditField_2.Value=app.ii-1;           
                        app.IRFmp{1,app.ii}=[];
                        app.del1=0;
                        h(app.ii)=h(app.ii+1);
                    end
                    if app.f==1 
                        app.f=0;                         
                        delete(gcf)                
                        app.ii=app.ii-1;
                        break 
                   end
                   
                   xy=getPosition(h(app.ii));
                    x=xy(:,1);
                    y=xy(:,2);
                    mask=createMask(h(app.ii));
                    app.z.contourx=x;
                    app.z.contoury=y;
                    app.z.mask=mask;
                    app.IRFmp{1,app.ii}=app.z;
                    app.NumberofObjectsEditField_ManualFluidTab.Value=app.ii;                   
                    t=mask+t ;
                    imshow(app.I(:,:,app.n2),'parent',app.UIAxes_ManualFluidTab);
                    hold(app.UIAxes_ManualFluidTab,'on');
                    app.ff=plot(app.UIAxes_ManualFluidTab,x,y,'r','linewidth',2); 
                 end
                    app.IRFmp{1,app.ii+1}=t;
                    IRFm=app.IRFmp;
                    name=strcat(app.file,'/fluid/manual/','Bscan-num',num2str(app.n2));
                    mkdir(name)
                    name1=strcat(name,'/IRFm.mat');
                    save(name1, 'IRFm')
                    
%                     uisave('IRFm','IRFm.mat');
                    for k=1:app.ii
                        hold(app.UIAxes_ManualFluidTab,'on');
                        plot(app.UIAxes_ManualFluidTab,app.IRFmp{1,k}.contourx,app.IRFmp{1,k}.contoury,'r','linewidth',2);
                    end
                    
                    fig = figure('Visible', false);
                    copyUIAxes(app.UIAxes_ManualFluidTab,fig);
                    axis off
                    saveas(fig, fullfile(name, 'final image with IRF_manual'), 'jpg');
                    delete(fig);
            end 
        end

        % Value changed function: SRFCheckBox_ManualFluidTab
        function SRFCheckBox_ManualFluidTabValueChanged(app, event)
            value = app.SRFCheckBox_ManualFluidTab.Value;
            if value==true
                app.SRFmp=[];
                app.ii=0;
                app.f=0;
                app.NumberofObjectsEditField_ManualFluidTab.Value=0;
                imshow(app.I(:,:,app.n2)); 
                app.Imb=imbinarize(app.I(:,:,app.n2));
                t=app.Imb.*0;
                while (1)                
                    app.ii=app.ii+1; 
                    h(app.ii)=imfreehand(gca,'closed',false);
                    h(app.ii).Deletable=false;
                    if app.del1==1
                        app.ii=app.ii-1;
                        h(app.ii).Deletable=true;
                        delete(h(app.ii));
                        delete(app.ff) 
                        t=t-mask ;
                       % app.numberofobjectsEditField_2.Value=app.i-1;           
                         app.SRFmp{1,app.ii}=[];
                         app.del1=0;
                         h(app.ii)=h(app.ii+1);
                      
                    end
                    if app.f==1 
                        app.f=0;                         
                        delete(gcf)                
                        app.ii=app.ii-1;
                        break 
                    end
                    xy=getPosition(h(app.ii));
                    x=xy(:,1);
                    y=xy(:,2);
                    mask=createMask(h(app.ii));
                    app.z.contourx=x;
                    app.z.contoury=y;
                    app.z.mask=mask;
                    app.SRFmp{1,app.ii}=app.z;
                    app.NumberofObjectsEditField_ManualFluidTab.Value=app.ii;                   
                    t=mask+t ;
                    imshow(app.I(:,:,app.n2),'parent',app.UIAxes_ManualFluidTab);
                    hold(app.UIAxes_ManualFluidTab,'on');
                 app.ff=plot(app.UIAxes_ManualFluidTab,x,y,'r','linewidth',2); 
                end
                app.SRFmp{1,app.ii+1}=t;
                SRFm=app.SRFmp;
                name=strcat(app.file,'/fluid/manual/','Bscan-num',num2str(app.n2));
                mkdir(name)
                name1=strcat(name,'/SRFm.mat');
                save(name1, 'SRFm')
%                 uisave('SRFm','SRFm.mat');
                for k=1:app.ii
                    hold(app.UIAxes_ManualFluidTab,'on');
                    plot(app.UIAxes_ManualFluidTab,app.SRFmp{1,k}.contourx,app.SRFmp{1,k}.contoury,'r','linewidth',2);
                end
                
                fig = figure('Visible', false);
                copyUIAxes(app.UIAxes_ManualFluidTab,fig);
                axis off
                saveas(fig, fullfile(name, 'final image with SRF_manual'), 'jpg');
                delete(fig);
             end
        end

        % Value changed function: PEDCheckBox_ManualFluidTab
        function PEDCheckBox_ManualFluidTabValueChanged(app, event)
            value = app.PEDCheckBox_ManualFluidTab.Value;
            if value==true
                app.PEDmp=[];
                app.ii=0;
                app.f=0;
                app.NumberofObjectsEditField_ManualFluidTab.Value=0;
                imshow(app.I(:,:,app.n2)); 
                app.Imb=imbinarize(app.I(:,:,app.n2));
                t=app.Imb.*0;
                while (1)                
                    app.ii=app.ii+1; 
                    h(app.ii)=imfreehand(gca,'closed',false);
                    h(app.ii).Deletable=false;
                    if app.del1==1
                        app.ii=app.ii-1;
                        h(app.ii).Deletable=true;
                        delete(h(app.ii));
                        delete(app.ff)
                        t=t-mask ;
                       % app.numberofobjectsEditField_2.Value=app.i-1;           
                         app.PEDmp{1,app.ii}=[];
                         app.del1=0;
                         h(app.ii)=h(app.ii+1);
                    end
                    if app.f==1 
                        app.f=0;                         
                        delete(gcf)                
                        app.ii=app.ii-1;
                        break 
                   end
                    xy=getPosition(h(app.ii));
                    x=xy(:,1);
                    y=xy(:,2);
                    mask=createMask(h(app.ii));
                    app.z.contourx=x;
                    app.z.contoury=y;
                    app.z.mask=mask;
                    app.PEDmp{1,app.ii}=app.z;
                    app.NumberofObjectsEditField_ManualFluidTab.Value=app.ii;                   
                    t=mask+t ;
                    imshow(app.I(:,:,app.n2),'parent',app.UIAxes_ManualFluidTab);
                    hold(app.UIAxes_ManualFluidTab,'on');
                 app.ff=plot(app.UIAxes_ManualFluidTab,x,y,'r','linewidth',2); 
                end
                app.PEDmp{1,app.ii+1}=t;
                PEDm=app.PEDmp;
                name=strcat(app.file,'/fluid/manual/','Bscan-num',num2str(app.n2));
                mkdir(name)
                name1=strcat(name,'/PEDm.mat');
                save(name1, 'PEDm')
%                 uisave('PEDm','PEDm.mat');
                for k=1:app.ii
                    hold(app.UIAxes_ManualFluidTab,'on');
                    plot(app.UIAxes_ManualFluidTab,app.PEDmp{1,k}.contourx,app.PEDmp{1,k}.contoury,'r','linewidth',2);
                end
                
                fig = figure('Visible', false);
                copyUIAxes(app.UIAxes_ManualFluidTab,fig);
                axis off
                saveas(fig, fullfile(name, 'final image with PED fluid_manual'), 'jpg');
                delete(fig);
            end
        end

        % Value changed function: ImageNumberEditField_ManualFluidTab
        function ImageNumberEditField_ManualFluidTabValueChanged(app, event)
            value = app.ImageNumberEditField_ManualFluidTab.Value;
            app.NumberofObjectsEditField_ManualFluidTab.Value=0;
            app.ImageNumberEditField_ManualFluidTab.Limits = [0 app.K];
            app.n2=value;
            %app.I(:,:,app.n2)=medfilt2(app.I(:,:,app.n2));
            %app.I(:,:,app.n2)=imadjust(app.I(:,:,app.n2),[0.3 0.7],[0.1 0.9],0.8);
            imshow(app.I(:,:,app.n2),'parent',app.UIAxes_ManualFluidTab);
        end

        % Button pushed function: DeleteButton_ManualFluidTab
        function DeleteButton_ManualFluidTabPushed(app, event)
            app.del1=true;
        end

        % Value changed function: ImageNumberEditField_SemiFluidTab
        function ImageNumberEditField_SemiFluidTabValueChanged(app, event)
            value = app.ImageNumberEditField_SemiFluidTab.Value;
            app.NumberofObjectsEditField_SemiFluidTab.Value=0;
            app.ImageNumberEditField_SemiFluidTab.Limits = [0 app.K];
            app.n1=value;
            %app.I(:,:,app.n1)=medfilt2(app.I(:,:,app.n1));
            %app.I(:,:,app.n1)=imadjust(app.I(:,:,app.n1),[0.3 0.7],[0.1 0.9],0.8);
%             app.UIAxes_SemiFluidTab.XAxis.TickLabels={};
%             app.UIAxes_SemiFluidTab.YAxis.TickLabels={};
            imshow(app.I(:,:,app.n1),'Parent',app.UIAxes_SemiFluidTab);
%             imshow(app.I(:,:,app.n1),'parent',app.UIAxes_SemiFluidTab)
        end

        % Button pushed function: FinishButton_ManualFluidTab
        function FinishButton_ManualFluidTabPushed(app, event)
            app.f=1;
        end

        % Value changed function: 
        % NumberofObjectsEditField_ManualFluidTab
        function NumberofObjectsEditField_ManualFluidTabValueChanged(app, event)
            value = app.NumberofObjectsEditField_ManualFluidTab.Value;
            
        end

        % Value changed function: 
        % NumberofObjectsEditField_SemiFluidTab
        function NumberofObjectsEditField_SemiFluidTabValueChanged(app, event)
            value = app.NumberofObjectsEditField_SemiFluidTab.Value;
            
        end

        % Button pushed function: DeleteButton_SemiFluidTab
        function DeleteButton_SemiFluidTabPushed(app, event)
           app.del2=true; 
        end

        % Button pushed function: FinishButton_SemiFluidTab
        function FinishButton_SemiFluidTabPushed(app, event)
            app.f=1;
            
        end

        % Value changed function: IRFCheckBox_SemiFluidTab
        function IRFCheckBox_SemiFluidTabValueChanged(app, event)
            value = app.IRFCheckBox_SemiFluidTab.Value;
            if value==true
                app.IRFp=[];
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes_GridTab);
                app.NumberofObjectsEditField_SemiFluidTab.Value=0;
                app.ii=0;
                I_denoise=app.I;
                I_denoise(:,:,app.n1)=medfilt2(app.I(:,:,app.n1));
                I_denoise(:,:,app.n1)=imadjust(app.I(:,:,app.n1),[0.3 0.7],[0.1 0.9],0.8);
                I_canny=edge(I_denoise(:,:,app.n1),'canny',app.num(11,6),app.num(11,7));
                se=strel('rectangle',[app.num(11,3) app.num(11,4)]);
                %se1=strel('line',5,90);
                I_canny_di=imdilate(I_canny,se);
                I_canny_di(:,1:2)=1;
                I_canny_di(:,app.M-1:app.M)=1;
                finalimg=zeros(app.M,app.N);
                while(1)
                    if app.f==1
                        app.f=0;
                        app.ii=app.ii-1;
                        break
                    end  
                    app.ii=app.ii+1;
                    [r_contour,contoury,contourx]=lwcontour(I_canny_di,app.I(:,:,app.n1),1,1000);
                    if app.del2==1
                       app.ii=app.ii-1;                   
                       delete(app.ff)          
                       app.IRFp{1,app.ii}=[];
                       finalimg=finalimg-bw;
                       app.del2=0;                   
                    end
                    app.NumberofObjectsEditField_SemiFluidTab.Value=app.ii;
                    contourx=smooth(contourx,5);
                    contoury=smooth(contoury,5);
                    imshow(app.I(:,:,app.n1),'parent',app.UIAxes_SemiFluidTab);
                    hold(app.UIAxes_SemiFluidTab,'on');
                    app.ff= plot(app.UIAxes_SemiFluidTab,contoury,contourx,'r','linewidth',2);
                    bw=roipoly(app.I(:,:,app.n1),contoury,contourx);
                    app.z.contourx=contourx;
                    app.z.contoury=contoury;
                    app.z.mask=bw;
                    app.IRFp{1,app.ii}=app.z;             
                    finalimg=finalimg+bw;
                end
                    app.NumberofObjectsEditField_SemiFluidTab.Value=app.ii;
                    app.IRFp{1,app.ii+1}=finalimg;
                    IRF=app.IRFp;
                    name=strcat(app.file,'/fluid/semi-auto/','Bscan-num',num2str(app.n1));
                    mkdir(name)
                    name1=strcat(name,'/IRF.mat');
                    save(name1, 'IRF')
                    for k=1:app.ii
                     hold(app.UIAxes_SemiFluidTab,'on');
                     plot(app.UIAxes_SemiFluidTab,app.IRFp{1,k}.contoury,app.IRFp{1,k}.contourx,'r','linewidth',2);
                    end                  
                    fig = figure('Visible', false);
                    copyUIAxes(app.UIAxes_SemiFluidTab,fig);
                    axis off
                    saveas(fig, fullfile(name, 'final image with IRF_semi-auto'), 'jpg');
                    delete(fig);
                    
            end
        end

        % Value changed function: SRFCheckBox_SemiFluidTab
        function SRFCheckBox_SemiFluidTabValueChanged(app, event)
            value = app.SRFCheckBox_SemiFluidTab.Value;
            if value==true
                app.SRFp=[];
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes_SemiFluidTab);
                app.NumberofObjectsEditField_SemiFluidTab.Value=0;
                app.ii=0; 
                
                I_denoise=app.I;
                I_denoise(:,:,app.n1)=medfilt2(app.I(:,:,app.n1));
                I_denoise(:,:,app.n1)=imadjust(app.I(:,:,app.n1),[0.3 0.7],[0.1 0.9],0.8);
                
                I_canny=edge(I_denoise(:,:,app.n1),'canny',app.num(11,6),app.num(11,7));
                se=strel('rectangle',[app.num(11,3) app.num(11,4)]);
                %se1=strel('line',5,90);
                I_canny_di=imdilate(I_canny,se);
                I_canny_di(:,1:2)=1;
                I_canny_di(:,app.M-1:app.M)=1;
                finalimg=zeros(app.M,app.N);
                while(1)
                   if app.f==1
                      app.f=0;
                      app.ii=app.ii-1;
                      break
                  end  
                  app.ii=app.ii+1;
                  [r_contour,contoury,contourx]=lwcontour(I_canny_di,app.I(:,:,app.n1),1,1000);  
                  if app.del2==1
                     app.ii=app.ii-1;                   
                     delete(app.ff)          
                     app.SRFp{1,app.ii}=[];
                     finalimg=finalimg-bw;
                     app.del2=0;                   
                 end
                 app.NumberofObjectsEditField_SemiFluidTab.Value=app.ii;
                 contourx=smooth(contourx,5);
                 contoury=smooth(contoury,5);
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes_SemiFluidTab);
                hold(app.UIAxes_SemiFluidTab,'on');
                plot(app.UIAxes_SemiFluidTab,contoury,contourx,'r','linewidth',2)
                bw=roipoly(app.I(:,:,app.n1),contoury,contourx);
                app.z.contourx=contourx;
                app.z.contoury=contoury;
                app.z.mask=bw;
                app.SRFp{1,app.ii}=app.z;             
                finalimg=finalimg+bw;
                end
                app.NumberofObjectsEditField_SemiFluidTab.Value=app.ii;
                app.SRFp{1,app.ii+1}=finalimg;
                SRF=app.SRFp;
                name=strcat(app.file,'/fluid/semi-auto/','Bscan-num',num2str(app.n1));
                mkdir(name)
                name1=strcat(name,'/SRF.mat');
                save(name1, 'SRF')
                
%                 uisave('SRF','SRF.mat');         
               for k=1:app.ii
                   hold(app.UIAxes_SemiFluidTab,'on');
                   plot(app.UIAxes_SemiFluidTab,app.SRFp{1,k}.contoury,app.SRFp{1,k}.contourx,'r','linewidth',2);
               end
               
                              
                fig = figure('Visible', false);
                copyUIAxes(app.UIAxes_SemiFluidTab,fig);
                axis off
                saveas(fig, fullfile(name, 'final image with SRF_semi-auto'), 'jpg');
                delete(fig);
                
            end 
            
        end

        % Value changed function: PEDCheckBox_SemiFluidTab
        function PEDCheckBox_SemiFluidTabValueChanged(app, event)
            value = app.PEDCheckBox_SemiFluidTab.Value;
            
            if value==true
                app.PEDp=[];
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes_SemiFluidTab);
                app.NumberofObjectsEditField_SemiFluidTab.Value=0;
                app.ii=0; 
                
                I_denoise=app.I;
                I_denoise(:,:,app.n1)=medfilt2(app.I(:,:,app.n1));
                I_denoise(:,:,app.n1)=imadjust(app.I(:,:,app.n1),[0.3 0.7],[0.1 0.9],0.8);
                
                I_canny=edge(I_denoise(:,:,app.n1),'canny',app.num(11,6),app.num(11,7));
                se=strel('rectangle',[app.num(11,3) app.num(11,4)]);
                %se1=strel('line',5,90);
                I_canny_di=imdilate(I_canny,se);
                I_canny_di(:,1:2)=1;
                I_canny_di(:,app.M-1:app.M)=1;
                finalimg=zeros(app.M,app.N);
                while(1)
                    
                 if app.f==1
                     app.f=0;
                     app.ii=app.ii-1;
                     
                     break
                 end  
                 
                 app.ii=app.ii+1;
                [r_contour,contoury,contourx]=lwcontour(I_canny_di,app.I(:,:,app.n1),1,1000);  
               
                if app.del2==1
                   app.ii=app.ii-1;                   
                   delete(app.ff)          
                   app.PEDp{1,app.ii}=[];
                   app.del2=0;
                   finalimg=finalimg-bw;
               end
               
                
                app.NumberofObjectsEditField_SemiFluidTab.Value=app.ii;
                contourx=smooth(contourx,5);
                contoury=smooth(contoury,5);
                
                imshow(app.I(:,:,app.n1),'parent',app.UIAxes_SemiFluidTab);
                hold(app.UIAxes_SemiFluidTab,'on');
                plot(app.UIAxes_SemiFluidTab,contoury,contourx,'r','linewidth',2)
                bw=roipoly(app.I(:,:,app.n1),contoury,contourx);
                app.z.contourx=contourx;
                app.z.contoury=contoury;
                app.z.mask=bw;
                app.PEDp{1,app.ii}=app.z;             
                finalimg=finalimg+bw;
                end
                app.NumberofObjectsEditField_SemiFluidTab.Value=app.ii;
                app.PEDp{1,app.ii+1}=finalimg;
                PED=app.PEDp;
                name=strcat(app.file,'/fluid/semi-auto/','Bscan-num',num2str(app.n1));
                mkdir(name)
                name1=strcat(name,'/PED.mat');
                save(name1, 'PED')
     
    %             uisave('PED','PED.mat');         
               
                for k=1:app.ii
                 hold(app.UIAxes_SemiFluidTab,'on');
                 plot(app.UIAxes_SemiFluidTab,app.PEDp{1,k}.contoury,app.PEDp{1,k}.contourx,'r','linewidth',2);
                end
                          
                fig = figure('Visible', false);
                copyUIAxes(app.UIAxes_SemiFluidTab,fig);
                axis off
                saveas(fig, fullfile(name, 'final image with PED fluid_semi-auto'), 'jpg');
                delete(fig);
            
            end 
     

        end

        % Callback function
        function ShowAllLayersandSaveButton_2Pushed(app, event)
           app.contourx_final=[app.contourxn1';app.contourxn2';app.contourxn3';app.contourxn4';app.contourxn5';app.contourxn6';app.contourxn7';app.contourxn8';app.contourxn9'];
            hold(app.UIAxes7, 'on');
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes7)
            for k=1:8 
             for j=1:app.N
                 if app.contourx_final(k,j)>app.contourx_final(k+1,j) || app.contourx_final(k,j)==app.contourx_final(k+1,j)
                    app.contourx_final(k+1,j)=app.contourx_final(k,j)+1;
                end
             end
            end

            plot(app.UIAxes7,1:app.N,app.contourx_final(1,:),'g','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(2,:),'y','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(3,:),'b','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(4,:),'m','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(5,:),'r','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(6,:),'g','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(7,:),'color','red','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(8,:),'color','y','LineWidth',0.5);
            plot(app.UIAxes7,1:app.N,app.contourx_final(9,:),'c','LineWidth',0.5); 
            finalLayer_semiauto= app.contourx_final;
            image=app.I(:,:,app.p);
%            uisave({'finalLayer_semiauto','image'},'layerinfo_semiauto.mat');
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name1=strcat(name,'/Alllayers_info.mat');
            name2=strcat(name,'/image.mat');
            save(name1, 'finalLayer_semiauto')
            save(name2, 'image')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes7,fig);
            axis off
            saveas(fig, fullfile(name, 'final image with layers'), 'jpg');
            delete(fig);
           
        end

        % Button pushed function: Boundary6Button_Peri
        function Boundary6Button_PeriPushed(app, event)
                %1.live wire on layer6 
                [Gx,Gy]=imgradientxy(app.Ig,'intermediate');
                [r_contour6,contoury6,contourx6] = lwcontour(Gy,app.Ig,1,500);
                [pcontouryn6,app.pcontourxn6]=resize_contour(contoury6,contourx6,app.num(20,1),app.Ig);
                imshow(app.Ig,'parent',app.UIAxes_Peri);
                hold(app.UIAxes_Peri,'on'); 
                plot(app.UIAxes_Peri,pcontouryn6,app.pcontourxn6,'c','LineWidth',0.5);
                [app.Ig2,app.d]=alignment(1,app.Ig,app.pcontourxn6);
                [Gx2,Gy2]=imgradientxy(app.Ig2);
                [mag2,dir2]=imgradient(Gx2,Gy2);
                 %2.live wire on layer6
        end

        % Button pushed function: Boundary3Button_Peri
        function Boundary3Button_PeriPushed(app, event)
                %1.live wire on layer3
                canny=edge(app.Ig2,'canny',[app.num(17,6) app.num(17,7)],'horizontal');
                [r_contour3,contoury3,contourx3]=lwcontour(canny,app.Ig2,1,1000);
                [pcontouryn3,app.pcontourxn3]=resize_contour(contoury3,contourx3,app.num(17,1),app.Ig);
                imshow(app.Ig2,'parent',app.UIAxes_Peri)
                hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,pcontouryn3,app.pcontourxn3,'color','m');
                %2.live wire on layer3

        end

        % Button pushed function: VeinDetectionButton_Peri
        function VeinDetectionButton_PeriPushed(app, event)
                app.Ig1=find_vein(app.Ig,app.pcontourxn3,1:app.N,app.Ig2);
                imshow(app.Ig1,'parent',app.UIAxes_Peri);
        end

        % Button pushed function: Boundary2Button_Peri
        function Boundary2Button_PeriPushed(app, event)
                se=strel('line',app.num(16,3),app.num(16,4));
                Ig1_canny=edge(app.Ig1,'canny',[app.num(16,6) app.num(16,7)],'horizontal');
                Ig1_canny_di=imdilate(Ig1_canny,se);
                figure,[r_contour2,contoury2,contourx2] = lwcontour(Ig1_canny_di,app.Ig1,1,1000);
                [pcontouryn2,app.pcontourxn2]=resize_contour(contoury2,contourx2,app.num(16,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes_Peri);
                hold(app.UIAxes_Peri,'on') 
                plot(app.UIAxes_Peri,pcontouryn2,app.pcontourxn2,'y','LineWidth',0.5);  
        end

        % Button pushed function: Boundary4Button_Peri
        function Boundary4Button_PeriPushed(app, event)
                 [Gx1,Gy1]=imgradientxy(app.Ig1);
                [mag1,dir1]=imgradient(Gx1,Gy1);
                se=strel('line',app.num(18,3),app.num(18,4));
                dir1n=abs(dir1./max(dir1(:))-1);
                dir1n_er=imerode(dir1n,se);
                [r_contour4,contoury4,contourx4]=lwcontour(dir1n_er,app.Ig1,1,1000);
                [pcontouryn4,app.pcontourxn4]=resize_contour(contoury4,contourx4,app.num(18,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes_Peri)
                hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,pcontouryn4,app.pcontourxn4,'color','red');  
                

        end

        % Button pushed function: Boundary3_finalButton_Peri
        function Boundary3_finalButton_PeriPushed(app, event)
                %1.live wire on layer3
                canny=edge(app.Ig1,'canny',[app.num(17,6) app.num(17,7)],'horizontal');
                se=strel('line',app.num(17,3),app.num(17,4));
                canny_di=imdilate(canny,se);            
                canny_dibb=black(round(app.pcontourxn4'-20),1:app.N,canny_di);
                [r_contour3,contoury3,contourx3]=lwcontour(canny_dibb,app.Ig1,1,1000);
                [pcontouryn3,app.pcontourxn3]=resize_contour(contoury3,contourx3,12,app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes_Peri)
                hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,pcontouryn3,app.pcontourxn3,'color','m');
                %2.live wire on layer3

        end

        % Button pushed function: Boundary5Button_Peri
        function Boundary5Button_PeriPushed(app, event)
                Ig1_canny=edge(app.Ig1,'canny',[app.num(19,6) app.num(19,7)],'horizontal');
                se=strel('line',app.num(19,3),app.num(19,4));
                canny_di=imdilate(Ig1_canny,se);
                canny_dib=blackl(round((app.pcontourxn4)'),1:app.N,canny_di);
                canny_dibb=blackl(round((app.pcontourxn3)'),1:app.N,canny_dib);
                [r_contour5,contoury5,contourx5]=lwcontour(canny_dibb,app.Ig1,1,1000);
                [pcontouryn5,app.pcontourxn5]=resize_contour(contoury5,contourx5,app.num(19,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes_Peri)
                hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,pcontouryn5,app.pcontourxn5,'color','green');

        end

        % Button pushed function: Boundary7Button_Peri
        function Boundary7Button_PeriPushed(app, event)
                [Gx1,Gy1]=imgradientxy(app.Ig1);
                [mag1,dir1]=imgradient(Gx1,Gy1);
                dir1black=blackl(round((app.pcontourxn6+app.d)'),1:app.N,dir1);
                [r_contour7,contoury7,contourx7] = lwcontour(dir1black,app.Ig1,1,500);
                [pcontouryn7,app.pcontourxn7]=resize_contour(contoury7,contourx7,app.num(21,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes_Peri)
                hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,pcontouryn7,app.pcontourxn7,'r','LineWidth',0.5); 

        end

        % Button pushed function: Boundary8Button_Peri
        function Boundary8Button_PeriPushed(app, event)
                [Gx1,Gy1]=imgradientxy(app.Ig1);
                [mag1,dir1]=imgradient(Gx1,Gy1);
                dir1b=blackl(round((app.pcontourxn6+app.d)'),1:app.N,dir1);
                dir1bb=blackl(round((app.pcontourxn7)'),1:app.N,dir1b);
                figure
                %[r_contour8,contoury8,contourx8]=lwcontour(dir1bb,app.Ig1,1,500);
               
                [r_contour8,contoury8,contourx8] = lwcontour(app.Ig1,app.Ig1,1,500);
                [pcontouryn8,app.pcontourxn8]=resize_contour(contoury8,contourx8,app.num(22,1),app.Ig);
                imshow(app.Ig1,'parent',app.UIAxes_Peri)
                hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,pcontouryn8,app.pcontourxn8,'c','LineWidth',0.5); 
                

        end

        % Button pushed function: Boundary1Button_Peri
        function Boundary1Button_PeriPushed(app, event)
                Ig_canny=edge(app.Ig,'canny',app.num(15,6),app.num(15,7));
                [r_contour1,contoury1,contourx1]=lwcontour(Ig_canny,app.Ig,1,1000);
                [pcontouryn1,app.pcontourxn1]=resize_contour(contoury1,contourx1,app.num(15,1),app.Ig);
                imshow(app.Ig,'parent',app.UIAxes_Peri);
                hold(app.UIAxes_Peri,'on') ,plot(app.UIAxes_Peri,pcontouryn1,app.pcontourxn1,'color','g');

        end

        % Button pushed function: ShowAllLayersandSaveButton_Peri
        function ShowAllLayersandSaveButton_PeriPushed(app, event)
             pcontourx_final=[app.pcontourxn1';smooth(app.pcontourxn2-app.d,10)';smooth(app.pcontourxn3-app.d,20)';smooth(app.pcontourxn4-app.d,20)';smooth(app.pcontourxn5-app.d,20)';app.pcontourxn6';(smooth(app.pcontourxn7-app.d,40))';(smooth(app.pcontourxn8-app.d,40))'];
            
            for k=1:7 
             for j=1:app.N
             if pcontourx_final(k,j)>pcontourx_final(k+1,j) || pcontourx_final(k,j)==pcontourx_final(k+1,j)
                 pcontourx_final(k+1,j)=pcontourx_final(k,j)+1;
             end
             end
            end
            [app.Ig3,app.d]=alignment(-1,app.Ig1,app.pcontourxn6);
            imshow(app.Ig3,'parent',app.UIAxes_Peri)
            hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,1:app.N,pcontourx_final(1,:),'color','c');
            hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,1:app.N,pcontourx_final(2,:),'color','y');
            hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,1:app.N,pcontourx_final(3,:),'color','red');
            hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,1:app.N,pcontourx_final(4,:),'color','green');
            hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,1:app.N,pcontourx_final(5,:),'color','c');
            hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,1:app.N,pcontourx_final(6,:),'color','[0.1 0.6 0.4]');
            hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,1:app.N,pcontourx_final(7,:),'y');
            hold(app.UIAxes_Peri,'on'),plot(app.UIAxes_Peri,1:app.N,pcontourx_final(8,:),'m');
            finalLayer_peripapillary=pcontourx_final;
            image=app.Ig;
%             uisave({'finalLayer_peripapillary','image'},'peripapillary_layerinfo.mat');

            name=strcat(app.file,'/peripapillary/');
            mkdir(name)
            name1=strcat(name,'/finalLayer_peripapillary.mat');
            name2=strcat(name,'/image.mat');
            save(name1, 'finalLayer_peripapillary')
            save(name2, 'image')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes_Peri,fig);
            axis off
            saveas(fig, fullfile(name, 'final peripapillary image with layers'), 'jpg');
            delete(fig);
        end

        % Button pushed function: LoadButton_Peri
        function LoadButton_PeriPushed(app, event)
%                 app.Ig=imread('E:\karamoozi\pripapillary\1.png');
                s=uiimport('-file');
                s=struct2cell(s);
                app.Ig=s{1};
                
                app.Ig=rgb2gray(app.Ig);
                imshow(app.Ig,'parent',app.UIAxes_Peri)
                [app.M,app.N]=size(app.Ig);                   
                
               app.pcontourxn1=NaN(1,app.N);
               app.pcontourxn2=NaN(1,app.N);
               app.pcontourxn3=NaN(1,app.N);
               app.pcontourxn4=NaN(1,app.N);
               app.pcontourxn5=NaN(1,app.N);
               app.pcontourxn6=NaN(1,app.N);
               app.pcontourxn7=NaN(1,app.N);
               app.pcontourxn8=NaN(1,app.N);
        end

        % Callback function
        function imagenumberEditField_4ValueChanged(app, event)
            value = app.imagenumberEditField_4.Value;
            app.imagenumberEditField_4.Limits = [1 app.K];
            app.n4=value;
            
            imshow(app.I(:,:,app.n4),'parent',app.UIAxes6);
        end

        % Callback function
        function startButtonPushed(app, event)
        
        end

        % Callback function
        function deleteButton_3Pushed(app, event)
            app.u=app.u-1;
            app.r=app.r-app.Phi;
            app.NumberofobjectsEditField_3.Value=app.u;
        end

        % Callback function
        function saveButton_2Pushed(app, event)
       
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
             app.I=imrotate(app.I,270);
             [app.M,app.N,app.K]=size(app.I);
             imshow(app.I(:,:,1),'Parent',app.UIAxes_FileTab);
%              for j=1:app.K
%              app.I(:,:,j)=medfilt2(app.I(:,:,j));
%              app.I(:,:,j)=imadjust(app.I(:,:,j),[0.3 0.7],[0.1 0.9],0.8);
%              end
        end

        % Value changed function: ImageNumberEditField_GridTab
        function ImageNumberEditField_GridTabValueChanged(app, event)
            value = app.ImageNumberEditField_GridTab.Value;
            app.ImageNumberEditField_GridTab.Limits = [0 app.K];
            app.p=value;
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes_GridTab);
            app.contourxn1_grid=NaN(app.N,1);
            app.contourxn2_grid=NaN(app.N,1);
            app.contourxn3_grid=NaN(app.N,1);
            app.contourxn4_grid=NaN(app.N,1);
            app.contourxn5_grid=NaN(app.N,1);
            app.contourxn6_grid=NaN(app.N,1);
            app.contourxn7_grid=NaN(app.N,1);
            app.contourxn8_grid=NaN(app.N,1);
            app.contourxn9_grid=NaN(app.N,1);
            app.time_grid=NaN(1,9);
            
 %           app.IrregularityIndexEditField_IPLGridTab.Value=0;
%             app.IrregularityIndexEditField_OPLGridTab.Value=0;
%             app.IrregularityIndexEditField_IPLGridCorrTab.Value=0;
%             app.IrregularityIndexEditField_OPLGridCorrTab.Value=0;
        end

        % Value changed function: ImageNumberEditField_ManualTab
        function ImageNumberEditField_ManualTabValueChanged(app, event)
            value = app.ImageNumberEditField_ManualTab.Value;
            app.ImageNumberEditField_ManualTab.Limits = [0 app.K];
            app.p=value;
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes_ManualTab);
            app.y1=NaN(1,app.N);
            app.y2=NaN(1,app.N);
            app.y3=NaN(1,app.N);
            app.y4=NaN(1,app.N);
            app.y5=NaN(1,app.N);
            app.y6=NaN(1,app.N);
            app.y7=NaN(1,app.N);
            app.y8=NaN(1,app.N);
            app.y9=NaN(1,app.N);
            app.time_manual=NaN(1,9);
        end

        % Button pushed function: ILMButton_ManualTab
        function ILMButton_ManualTabPushed(app, event)
            warning off
            [T,app.y1]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_ManualTab)
            hold(app.UIAxes_ManualTab,'on'),plot(app.UIAxes_ManualTab,1:app.N,app.y1,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ILM.mat');
            layer1 = app.y1;
            save(name, 'layer1')
            app.time_manual(1,1)=T;
        end

        % Button pushed function: NFLGCLButton_ManualTab
        function NFLGCLButton_ManualTabPushed(app, event)
            warning off
            [T,app.y2]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_ManualTab)
            hold(app.UIAxes_ManualTab,'on'),plot(app.UIAxes_ManualTab,1:app.N,app.y2,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/NFL-GCL.mat');
            layer2 = app.y2;
            save(name, 'layer2')
            app.time_manual(1,2)=T;
        end

        % Button pushed function: GCLIPLButton_ManualTab
        function GCLIPLButton_ManualTabPushed(app, event)
            warning off
            [T,app.y3]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_ManualTab)
            hold(app.UIAxes_ManualTab,'on'),plot(app.UIAxes_ManualTab,1:app.N,app.y3,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/IPL-INL.mat');
            layer3 = app.y3;
            save(name, 'layer3')
            app.time_manual(1,3)=T;
        end

        % Button pushed function: IPLINLButton_ManualTab
        function IPLINLButton_ManualTabPushed(app, event)
            warning off
            [T,app.y4]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_ManualTab)
            hold(app.UIAxes_ManualTab,'on'),plot(app.UIAxes_ManualTab,1:app.N,app.y4,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/GCL-IPL.mat');
            layer4 = app.y4;
            save(name, 'layer4')
            app.time_manual(1,4)=T;
        end

        % Button pushed function: INLOPLButton_ManualTab
        function INLOPLButton_ManualTabPushed(app, event)
            warning off
            [T,app.y5]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_ManualTab)
            hold(app.UIAxes_ManualTab,'on'),plot(app.UIAxes_ManualTab,1:app.N,app.y5,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/INL-OPL.mat');
            layer5 = app.y5;
            save(name, 'layer5')
            app.time_manual(1,5)=T;
        end

        % Button pushed function: OPLONLButton_ManualTab
        function OPLONLButton_ManualTabPushed(app, event)
            warning off
            [T,app.y6]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_ManualTab)
            hold(app.UIAxes_ManualTab,'on'),plot(app.UIAxes_ManualTab,1:app.N,app.y6,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/OPL-ONL.mat');
            layer6 = app.y6;
            save(name, 'layer6')
            app.time_manual(1,6)=T;
        end

        % Button pushed function: ONLISOSButton_ManualTab
        function ONLISOSButton_ManualTabPushed(app, event)
            warning off
            [T,app.y7]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_ManualTab)
            hold(app.UIAxes_ManualTab,'on'),plot(app.UIAxes_ManualTab,1:app.N,app.y7,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ONL-ISOS.mat');
            layer7 = app.y7;
            save(name, 'layer7')
            app.time_manual(1,7)=T;
        end

        % Button pushed function: ISOSRPEButton_ManualTab
        function ISOSRPEButton_ManualTabPushed(app, event)
            warning off
            [T,app.y8]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_ManualTab)
            hold(app.UIAxes_ManualTab,'on'),plot(app.UIAxes_ManualTab,1:app.N,app.y8,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ISOS-RPE.mat');
            layer8 = app.y8;
            save(name, 'layer8')
            app.time_manual(1,8)=T;
        end

        % Button pushed function: BMChoroidButton_ManualTab
        function BMChoroidButton_ManualTabPushed(app, event)
            warning off
            [T,app.y9]=free_hand(app.I(:,:,app.p));
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_ManualTab)
            hold(app.UIAxes_ManualTab,'on'),plot(app.UIAxes_ManualTab,1:app.N,app.y9,'color','red');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/BM-Choroid.mat');
            layer9 = app.y9;
            save(name, 'layer9')
            app.time_manual(1,9)=T;
        end

        % Button pushed function: ShowAllLayersandSaveButton_ManualTab
        function ShowAllLayersandSaveButton_ManualTabPushed(app, event)
            app.contoury_final=[app.y1;app.y2;app.y3;app.y4;app.y5;app.y6;app.y7;app.y8;app.y9];
            hold(app.UIAxes_ManualTab, 'on');
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes_ManualTab)
            for k=1:8 
             for j=1:app.N
                 if app.contoury_final(k,j)>app.contoury_final(k+1,j) || app.contoury_final(k,j)==app.contoury_final(k+1,j)
                    app.contoury_final(k+1,j)=app.contoury_final(k,j)+1;
                end
             end
            end

            plot(app.UIAxes_ManualTab,1:app.N,app.contoury_final(1,:),'g','LineWidth',0.5);
            plot(app.UIAxes_ManualTab,1:app.N,app.contoury_final(2,:),'y','LineWidth',0.5);
            plot(app.UIAxes_ManualTab,1:app.N,app.contoury_final(3,:),'b','LineWidth',0.5);
            plot(app.UIAxes_ManualTab,1:app.N,app.contoury_final(4,:),'m','LineWidth',0.5);
            plot(app.UIAxes_ManualTab,1:app.N,app.contoury_final(5,:),'r','LineWidth',0.5);
            plot(app.UIAxes_ManualTab,1:app.N,app.contoury_final(6,:),'g','LineWidth',0.5);
            plot(app.UIAxes_ManualTab,1:app.N,app.contoury_final(7,:),'color','red','LineWidth',0.5);
            plot(app.UIAxes_ManualTab,1:app.N,app.contoury_final(8,:),'color','y','LineWidth',0.5);
            plot(app.UIAxes_ManualTab,1:app.N,app.contoury_final(9,:),'c','LineWidth',0.5); 
            finalLayer_freehand= app.contoury_final;
            image=app.I(:,:,app.p);
            Time=app.time_manual;
%             uisave({'finalLayer_freehand','image'},'layerinfo_freehand.mat');
            name=strcat(app.file,'/layer/manual/','Bscan-num',num2str(app.p));
            mkdir(name)
            name1=strcat(name,'/Alllayers_info.mat');
            name2=strcat(name,'/image.mat');
            name3=strcat(name,'/Time.mat');
            save(name1, 'finalLayer_freehand')
            save(name2, 'image')
            save(name3,'Time')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes_ManualTab,fig);
            axis off
            saveas(fig, fullfile(name, 'final image with layers'), 'jpg');
            delete(fig);
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            warning off
            value = app.FileNameEditField.Value;
            app.file=value;
            mkdir(app.file);
            mkdir(app.file,'layer/manual')
            mkdir(app.file,'layer/semi-auto')
            mkdir(app.file,'layer/grid')
            mkdir(app.file,'fluid/manual')
            mkdir(app.file,'fluid/semi-auto')
        end

        % Button pushed function: LoadExcelFileButton
        function LoadExcelFileButtonPushed(app, event)
            filename = 'Parameters.xlsx';
            [app.num,txt,raw] = xlsread(filename);
            
        end

        % Value changed function: ILMCheckBox_SemiCorrTab
        function ILMCheckBox_SemiCorrTabValueChanged(app, event)
            value = app.ILMCheckBox_SemiCorrTab.Value;
            if value==true
            [app.contourxn1,app.contouryn1]=manual_correction(app.I(:,:,app.p),app.contourxn1,app.contouryn1,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_SemiCorrTab)
            hold(app.UIAxes_SemiCorrTab, 'on')
            plot(app.UIAxes_SemiCorrTab,app.contouryn1,app.contourxn1,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ILM.mat');
            layer1_auto = app.contourxn1;
            save(name, 'layer1_auto')
            
        end

        % Value changed function: NFLGCLCheckBox_SemiCorrTab
        function NFLGCLCheckBox_SemiCorrTabValueChanged(app, event)
            value = app.NFLGCLCheckBox_SemiCorrTab.Value;
            if value==true
            [app.contourxn2,app.contouryn2]=manual_correction(app.I(:,:,app.p),app.contourxn2,app.contouryn2,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_SemiCorrTab)
            hold(app.UIAxes_SemiCorrTab, 'on')
            plot(app.UIAxes_SemiCorrTab,app.contouryn2,app.contourxn2,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/NFL-GCL.mat');
            layer2_auto = app.contourxn2;
            save(name, 'layer2_auto')
            
        end

        % Value changed function: GCLIPLCheckBox_SemiCorrTab
        function GCLIPLCheckBox_SemiCorrTabValueChanged(app, event)
            value = app.GCLIPLCheckBox_SemiCorrTab.Value;
            if value==true
            [app.contourxn3,app.contouryn3]=manual_correction(app.I(:,:,app.p),app.contourxn3,app.contouryn3,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_SemiCorrTab)
            hold(app.UIAxes_SemiCorrTab, 'on')
            plot(app.UIAxes_SemiCorrTab,app.contouryn3,app.contourxn3,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/GCL-IPL.mat');
            layer3_auto = app.contourxn3;
            save(name, 'layer3_auto')
            
        end

        % Value changed function: IPLINLCheckBox_SemiCorrTab
        function IPLINLCheckBox_SemiCorrTabValueChanged(app, event)
            value = app.IPLINLCheckBox_SemiCorrTab.Value;
            if value==true
            [app.contourxn4,app.contouryn4]=manual_correction(app.I(:,:,app.p),app.contourxn4,app.contouryn4,app.g1);
            delete(gcf)
            app.index_IPL_semi=irregularity_index(app.contourxn4);
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_SemiCorrTab)
            hold(app.UIAxes_SemiCorrTab, 'on')
            plot(app.UIAxes_SemiCorrTab,app.contouryn4,app.contourxn4,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/IPL-INL.mat');
            layer4_auto = app.contourxn4;
            save(name, 'layer4_auto')
            
        end

        % Value changed function: INLOPLCheckBox_SemiCorrTab
        function INLOPLCheckBox_SemiCorrTabValueChanged(app, event)
            value = app.INLOPLCheckBox_SemiCorrTab.Value;
            if value==true 
            [app.contourxn5,app.contouryn5]=manual_correction(app.I(:,:,app.p),app.contourxn5,app.contouryn5,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_SemiCorrTab)
            hold(app.UIAxes_SemiCorrTab, 'on')
            plot(app.UIAxes_SemiCorrTab,app.contouryn5,app.contourxn5,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/INL-OPL.mat');
            layer5_auto = app.contourxn5;
            save(name, 'layer5_auto')
            
        end

        % Value changed function: OPLONLCheckBox_SemiCorrTab
        function OPLONLCheckBox_SemiCorrTabValueChanged(app, event)
            value = app.OPLONLCheckBox_SemiCorrTab.Value;
            if value==true
            [app.contourxn6,app.contouryn6]=manual_correction(app.I(:,:,app.p),app.contourxn6,app.contouryn6,app.g1);
            delete(gcf)
            app.index_OPL_semi=irregularity_index(app.contourxn6);
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_SemiCorrTab)
            hold(app.UIAxes_SemiCorrTab, 'on')
            plot(app.UIAxes_SemiCorrTab,app.contouryn6,app.contourxn6,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/OPL-ONL.mat');
            layer6_auto = app.contourxn6;
            save(name, 'layer6_auto')
            
        end

        % Value changed function: ONLISOSCheckBox_SemiCorrTab
        function ONLISOSCheckBox_SemiCorrTabValueChanged(app, event)
            value = app.ONLISOSCheckBox_SemiCorrTab.Value;
            if value==true 
            [app.contourxn7,app.contouryn7]=manual_correction(app.I(:,:,app.p),app.contourxn7,app.contouryn7,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_SemiCorrTab)
            hold(app.UIAxes_SemiCorrTab, 'on')
            plot(app.UIAxes_SemiCorrTab,app.contouryn7,app.contourxn7,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ONL-ISOS.mat');
            layer7_auto = app.contourxn7;
            save(name, 'layer7_auto')
            
        end

        % Value changed function: ISOSRPECheckBox_SemiCorrTab
        function ISOSRPECheckBox_SemiCorrTabValueChanged(app, event)
            value = app.ISOSRPECheckBox_SemiCorrTab.Value;
            if value==true
            [app.contourxn8,app.contouryn8]=manual_correction(app.I(:,:,app.p),app.contourxn8,app.contouryn8,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_SemiCorrTab)
            hold(app.UIAxes_SemiCorrTab, 'on')
            plot(app.UIAxes_SemiCorrTab,app.contouryn8,app.contourxn8,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/ISOS-RPE.mat');
            layer8_auto = app.contourxn8;
            save(name, 'layer8_auto')
            
        end

        % Value changed function: BMChoroidCheckBox_SemiCorrTab
        function BMChoroidCheckBox_SemiCorrTabValueChanged(app, event)
            value = app.BMChoroidCheckBox_SemiCorrTab.Value;
            if value==true
            [app.contourxn9,app.contouryn9]=manual_correction(app.I(:,:,app.p),app.contourxn9,app.contouryn9,app.g1);
            delete(gcf)
            imshow(app.I(:,:,app.p),'parent',app.UIAxes_SemiCorrTab)
            hold(app.UIAxes_SemiCorrTab, 'on')
            plot(app.UIAxes_SemiCorrTab,app.contouryn9,app.contourxn9,'color','[0.6 1 0.2]');
            end
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name=strcat(name,'/BM-Choroid.mat');
            layer9_auto = app.contourxn9;
            save(name, 'layer9_auto')
            
        end

        % Value changed function: GridEditField_SemiCorrTab
        function GridEditField_SemiCorrTabValueChanged(app, event)
            value = app.GridEditField_SemiCorrTab.Value;
        
            app.g1=value;
        end

        % Button pushed function: 
        % ShowAllLayersandSaveButton_SemiCorrTab
        function ShowAllLayersandSaveButton_SemiCorrTabPushed(app, event)
            app.contourx_final=[app.contourxn1';app.contourxn2';app.contourxn3';app.contourxn4';app.contourxn5';app.contourxn6';app.contourxn7';app.contourxn8';app.contourxn9'];
            hold(app.UIAxes_SemiTab, 'on');
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes_SemiCorrTab)
            for k=1:8 
             for j=1:app.N
                 if app.contourx_final(k,j)>app.contourx_final(k+1,j) || app.contourx_final(k,j)==app.contourx_final(k+1,j)
                    app.contourx_final(k+1,j)=app.contourx_final(k,j)+1;
                end
             end
            end

            plot(app.UIAxes_SemiCorrTab,1:app.N,app.contourx_final(1,:),'g','LineWidth',0.5);
            plot(app.UIAxes_SemiCorrTab,1:app.N,app.contourx_final(2,:),'y','LineWidth',0.5);
            plot(app.UIAxes_SemiCorrTab,1:app.N,app.contourx_final(3,:),'b','LineWidth',0.5);
            plot(app.UIAxes_SemiCorrTab,1:app.N,app.contourx_final(4,:),'m','LineWidth',0.5);
            plot(app.UIAxes_SemiCorrTab,1:app.N,app.contourx_final(5,:),'r','LineWidth',0.5);
            plot(app.UIAxes_SemiCorrTab,1:app.N,app.contourx_final(6,:),'g','LineWidth',0.5);
            plot(app.UIAxes_SemiCorrTab,1:app.N,app.contourx_final(7,:),'color','red','LineWidth',0.5);
            plot(app.UIAxes_SemiCorrTab,1:app.N,app.contourx_final(8,:),'color','y','LineWidth',0.5);
            plot(app.UIAxes_SemiCorrTab,1:app.N,app.contourx_final(9,:),'c','LineWidth',0.5); 
            finalLayer_semiauto= app.contourx_final;
            image=app.I(:,:,app.p);
            Time=app.time_semi;
            
%             if ~isnan(app.contourx_final(4,:))
%             app.index_IPL_semi=irregularity_index(app.contourx_final(4,:));
%             end
%             if ~isnan(app.contourx_final(6,:))
%             app.index_OPL_semi=irregularity_index(app.contourx_final(6,:));
%             end
%             index=[app.index_IPL_semi;app.index_OPL_semi];
            
%            uisave({'finalLayer_semiauto','image'},'layerinfo_semiauto.mat');
            name=strcat(app.file,'/layer/semi-auto/','Bscan-num',num2str(app.p));
            mkdir(name)
            name1=strcat(name,'/Alllayers_info.mat');
            name2=strcat(name,'/image.mat');
            name3=strcat(name,'/Time.mat');
%             name4=strcat(name,'/Irregularity Index.mat');
            save(name1, 'finalLayer_semiauto')
            save(name2, 'image')
            save(name3, 'Time')
%             save(name4, 'index')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes_SemiCorrTab,fig);
            axis off
            saveas(fig, fullfile(name, 'final image with layers'), 'jpg');
            delete(fig); 
            
%             app.IrregularityIndexEditField_IPLSemiCorrTab.Value=app.index_IPL_semi;
%             app.IrregularityIndexEditField_OPLSemiCorrTab.Value=app.index_OPL_semi;
%             app.IrregularityIndexEditField_IPLSemiTab.Value=app.index_IPL_semi;
%             app.IrregularityIndexEditField_OPLSemiTab.Value=app.index_OPL_semi;
        end

        % Value changed function: 
        % IrregularityIndexEditField_IPLSemiTab
        function IrregularityIndexEditField_IPLSemiTabValueChanged(app, event)
            value = app.IrregularityIndexEditField_IPLSemiTab.Value;
            app.IrregularityIndexEditField_IPLSemiTab.Value=num2str(app.index_IPL_semi);
        end

        % Value changed function: 
        % IrregularityIndexEditField_OPLSemiTab
        function IrregularityIndexEditField_OPLSemiTabValueChanged(app, event)
            value = app.IrregularityIndexEditField_OPLSemiTab.Value;
            app.IrregularityIndexEditField_OPLSemiTab.Value=num2str(app.index_OPL_semi);
        end

        % Callback function
        function IrregularityIndexEditField_IPLSemiCorrTabValueChanged(app, event)
            value = app.IrregularityIndexEditField_IPLSemiCorrTab.Value;
            app.IrregularityIndexEditField_IPLSemiCorrTab.Value=num2str(app.index_IPL_semi);
        end

        % Callback function
        function IrregularityIndexEditField_OPLSemiCorrTabValueChanged(app, event)
            value = app.IrregularityIndexEditField_OPLSemiCorrTab.Value;
            app.IrregularityIndexEditField_OPLSemiCorrTab.Value=num2str(app.index_OPL_semi);
        end

        % Callback function
        function IrregularityIndexEditField_IPLGridTabValueChanged(app, event)
            value = app.IrregularityIndexEditField_IPLGridTab.Value;
            app.IrregularityIndexEditField_IPLGridTab.Value=num2str(app.index_IPL_grid);
        end

        % Callback function
        function IrregularityIndexEditField_OPLGridTabValueChanged(app, event)
            value = app.IrregularityIndexEditField_OPLGridTab.Value;
            app.IrregularityIndexEditField_OPLGridTab.Value=num2str(app.index_OPL_grid);
        end

        % Button pushed function: ShowAllLayersandSaveButton_GridTab
        function ShowAllLayersandSaveButton_GridTabPushed(app, event)
            app.contourx_final=[app.contourxn1_grid';app.contourxn2_grid';app.contourxn3_grid';app.contourxn4_grid';app.contourxn5_grid';app.contourxn6_grid';app.contourxn7_grid';app.contourxn8_grid';app.contourxn9_grid'];
            hold(app.UIAxes_GridTab, 'on');
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes_GridTab)
            for k=1:8 
             for j=1:app.N
                 if app.contourx_final(k,j)>app.contourx_final(k+1,j) || app.contourx_final(k,j)==app.contourx_final(k+1,j)
                    app.contourx_final(k+1,j)=app.contourx_final(k,j)+1;
                end
             end
            end

            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(1,:),'g','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(2,:),'y','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(3,:),'b','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(4,:),'m','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(5,:),'r','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(6,:),'g','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(7,:),'color','red','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(8,:),'color','y','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(9,:),'c','LineWidth',0.5); 
            finalLayer_grid= app.contourx_final;
            image=app.I(:,:,app.p);
            Time=app.time_grid;
            
%             if ~isnan(app.contourx_final(4,:))
%                 
%             app.index_IPL_grid=irregularity_index(app.contourx_final(4,:));
%             end
%              if ~isnan(app.contourx_final(6,:))
%             app.index_OPL_grid=irregularity_index(app.contourx_final(6,:));
%              end
%             index=[app.index_IPL_grid;app.index_OPL_grid];
            
%            uisave({'finalLayer_semiauto','image'},'layerinfo_semiauto.mat');
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name1=strcat(name,'/Alllayers_info.mat');
            name2=strcat(name,'/image.mat');
            name3=strcat(name,'/Time.mat');
%             name4=strcat(name,'/Irregularity Index.mat');
            save(name1, 'finalLayer_grid')
            save(name2, 'image')
            save(name3, 'Time')
%             save(name4,'index')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes_GridTab,fig);
            axis off
            saveas(fig, fullfile(name, 'final image with layers'), 'jpg');
            delete(fig); 
            
%             app.IrregularityIndexEditField_IPLGridTab.Value=app.index_IPL_grid;
%             app.IrregularityIndexEditField_OPLGridTab.Value=app.index_OPL_grid;
        end

        % Callback function
        function IrregularityIndexEditField_IPLGridCorrTabValueChanged(app, event)
            value = app.IrregularityIndexEditField_IPLGridCorrTab.Value;
            app.IrregularityIndexEditField_IPLGridCorrTab.Value=num2str(app.index_IPL_grid);
        end

        % Callback function
        function IrregularityIndexEditField_OPLGridCorrTabValueChanged(app, event)
            value = app.IrregularityIndexEditField_OPLGridCorrTab.Value;
            app.IrregularityIndexEditField_OPLGridCorrTab.Value=num2str(app.index_OPL_grid);
        end

        % Button pushed function: 
        % ShowAllLayersandSaveButton_GridCorrTab
        function ShowAllLayersandSaveButton_GridCorrTabPushed(app, event)
            app.contourx_final=[app.contourxn1_grid';app.contourxn2_grid';app.contourxn3_grid';app.contourxn4_grid';app.contourxn5_grid';app.contourxn6_grid';app.contourxn7_grid';app.contourxn8_grid';app.contourxn9_grid'];
            hold(app.UIAxes_GridTab, 'on');
            imshow(app.I(:,:,app.p),'Parent',app.UIAxes_GridTab)
            for k=1:8 
             for j=1:app.N
                 if app.contourx_final(k,j)>app.contourx_final(k+1,j) || app.contourx_final(k,j)==app.contourx_final(k+1,j)
                    app.contourx_final(k+1,j)=app.contourx_final(k,j)+1;
                end
             end
            end

            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(1,:),'g','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(2,:),'y','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(3,:),'b','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(4,:),'m','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(5,:),'r','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(6,:),'g','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(7,:),'color','red','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(8,:),'color','y','LineWidth',0.5);
            plot(app.UIAxes_GridTab,1:app.N,app.contourx_final(9,:),'c','LineWidth',0.5); 
            finalLayer_grid= app.contourx_final;
            image=app.I(:,:,app.p);
            Time=app.time_grid;
%             if ~isnan(app.contourx_final(4,:))
%             app.index_IPL_grid=irregularity_index(app.contourx_final(4,:));
%             end
%             if ~isnan(app.contourx_final(6,:))
%             app.index_OPL_grid=irregularity_index(app.contourx_final(6,:));
%             end
%             index=[app.index_IPL_grid;app.index_OPL_grid];
            
%            uisave({'finalLayer_semiauto','image'},'layerinfo_semiauto.mat');
            name=strcat(app.file,'/layer/grid/','Bscan-num',num2str(app.p));
            mkdir(name)
            name1=strcat(name,'/Alllayers_info.mat');
            name2=strcat(name,'/image.mat');
            name3=strcat(name,'/Time.mat');
%             name4=strcat(name,'/Irregularity Index.mat');
            save(name1, 'finalLayer_grid')
            save(name2, 'image')
            save(name3, 'Time')
%             save(name4, 'index')
            
            fig = figure('Visible', false);
            copyUIAxes(app.UIAxes_GridTab,fig);
            axis off
            saveas(fig, fullfile(name, 'final image with layers'), 'jpg');
            delete(fig);
            
%             app.IrregularityIndexEditField_IPLGridTab.Value=app.index_IPL_grid;
%             app.IrregularityIndexEditField_OPLGridTab.Value=app.index_OPL_grid;
%             app.IrregularityIndexEditField_IPLGridCorrTab.Value=app.index_IPL_grid;
%             app.IrregularityIndexEditField_OPLGridCorrTab.Value=app.index_OPL_grid;
        end

        % Callback function
        function IrregularityIndexEditField_IPLGridTabValueChanged2(app, event)
            value = app.IrregularityIndexEditField_IPLGridTab.Value;
            app.IrregularityIndexEditField_IPLGridTab.Value=num2str(app.index_IPL_grid);
        end

        % Size changed function: SemiAutomaticTab
        function SemiAutomaticTabSizeChanged(app, event)
            position = app.SemiAutomaticTab.Position;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.UIAxes_FileTab = uiaxes(app.FileTab);
            app.Panel_3 = uipanel(app.FileTab);
            app.EditField_ImageNumber_FileTab = uieditfield(app.Panel_3, 'text');
            app.EditField_ImageNumber_FileTab.ValueChangedFcn = createCallbackFcn(app, @EditField_ImageNumber_FileTabValueChanged, true);
            app.EditField_ImageNumber_FileTab.Editable = 'off';
            app.ImageNumberSpinnerLabel = uilabel(app.Panel_3);
            app.ImageNumberSpinnerLabel.Text = 'Image Number  ';
            app.ImageNumberSpinner_FileTab = uispinner(app.Panel_3);
            app.ImageNumberSpinner_FileTab.Limits = [1 Inf];
            app.ImageNumberSpinner_FileTab.ValueDisplayFormat = '%.0f';
            app.ImageNumberSpinner_FileTab.ValueChangedFcn = createCallbackFcn(app, @ImageNumberSpinner_FileTabValueChanged, true);
            app.ImageNumberSpinner_FileTab.Value = 1;
            app.matButton = uibutton(app.Panel_3, 'push');
            app.matButton.ButtonPushedFcn = createCallbackFcn(app, @matButtonPushed, true);
            app.matButton.Text = '.mat';
            app.volButton = uibutton(app.Panel_3, 'push');
            app.volButton.ButtonPushedFcn = createCallbackFcn(app, @volButtonPushed, true);
            app.volButton.Text = '.vol';
            app.octbinButton = uibutton(app.Panel_3, 'push');
            app.octbinButton.ButtonPushedFcn = createCallbackFcn(app, @octbinButtonPushed, true);
            app.octbinButton.Text = '.octbin';
            app.FileNameEditFieldLabel = uilabel(app.FileTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.FileTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'E:/default';
            app.RotateButton = uibutton(app.FileTab, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.LoadExcelFileButton = uibutton(app.FileTab, 'push');
            app.LoadExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExcelFileButtonPushed, true);
            app.LoadExcelFileButton.Text = 'Load Excel File';
            app.ManualLayerSegmentationTab = uitab(app.TabGroup);
            app.ManualLayerSegmentationTab.Title = 'Manual Layer Segmentation';
            app.BoundariesPanel_2 = uipanel(app.ManualLayerSegmentationTab);
            app.BoundariesPanel_2.Title = 'Boundaries';
            app.ILMButton_ManualTab = uibutton(app.BoundariesPanel_2, 'push');
            app.ILMButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @ILMButton_ManualTabPushed, true);
            app.ILMButton_ManualTab.Text = 'ILM';
            app.NFLGCLButton_ManualTab = uibutton(app.BoundariesPanel_2, 'push');
            app.NFLGCLButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @NFLGCLButton_ManualTabPushed, true);
            app.NFLGCLButton_ManualTab.Text = 'NFL-GCL';
            app.INLOPLButton_ManualTab = uibutton(app.BoundariesPanel_2, 'push');
            app.INLOPLButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @INLOPLButton_ManualTabPushed, true);
            app.INLOPLButton_ManualTab.Text = 'INL-OPL';
            app.OPLONLButton_ManualTab = uibutton(app.BoundariesPanel_2, 'push');
            app.OPLONLButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @OPLONLButton_ManualTabPushed, true);
            app.OPLONLButton_ManualTab.Text = 'OPL-ONL';
            app.ONLISOSButton_ManualTab = uibutton(app.BoundariesPanel_2, 'push');
            app.ONLISOSButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @ONLISOSButton_ManualTabPushed, true);
            app.ONLISOSButton_ManualTab.Text = 'ONL-IS/OS';
            app.ISOSRPEButton_ManualTab = uibutton(app.BoundariesPanel_2, 'push');
            app.ISOSRPEButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @ISOSRPEButton_ManualTabPushed, true);
            app.ISOSRPEButton_ManualTab.Text = 'IS/OS-RPE';
            app.BMChoroidButton_ManualTab = uibutton(app.BoundariesPanel_2, 'push');
            app.BMChoroidButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @BMChoroidButton_ManualTabPushed, true);
            app.BMChoroidButton_ManualTab.Text = 'BM-Choroid';
            app.IPLINLButton_ManualTab = uibutton(app.BoundariesPanel_2, 'push');
            app.IPLINLButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @IPLINLButton_ManualTabPushed, true);
            app.IPLINLButton_ManualTab.Text = 'IPL-INL';
            app.GCLIPLButton_ManualTab = uibutton(app.BoundariesPanel_2, 'push');
            app.GCLIPLButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @GCLIPLButton_ManualTabPushed, true);
            app.GCLIPLButton_ManualTab.Text = 'GCL-IPL';
            app.UIAxes_ManualTab = uiaxes(app.ManualLayerSegmentationTab);
            app.ImageNumberEditFieldLabel = uilabel(app.ManualLayerSegmentationTab);
            app.ImageNumberEditFieldLabel.Text = 'Image Number';
            app.ImageNumberEditField_ManualTab = uieditfield(app.ManualLayerSegmentationTab, 'numeric');
            app.ImageNumberEditField_ManualTab.Limits = [0 Inf];
            app.ImageNumberEditField_ManualTab.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField_ManualTab.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditField_ManualTabValueChanged, true);
            app.ShowAllLayersandSaveButton_ManualTab = uibutton(app.ManualLayerSegmentationTab, 'push');
            app.ShowAllLayersandSaveButton_ManualTab.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersandSaveButton_ManualTabPushed, true);
            app.ShowAllLayersandSaveButton_ManualTab.Text = 'Show All Layers and Save';
            app.AutoLayerSegmentationTab = uitab(app.TabGroup);
            app.AutoLayerSegmentationTab.Title = 'Auto Layer Segmentation ';
            app.TabGroup2 = uitabgroup(app.AutoLayerSegmentationTab);
            app.SemiAutomaticTab = uitab(app.TabGroup2);
            app.SemiAutomaticTab.SizeChangedFcn = createCallbackFcn(app, @SemiAutomaticTabSizeChanged, true);
            app.SemiAutomaticTab.Title = 'Semi-Automatic';
            app.ImageNumberEditField_5Label = uilabel(app.SemiAutomaticTab);
            app.ImageNumberEditField_5Label.Text = 'Image Number';
            app.ImageNumberEditField_SemiTab = uieditfield(app.SemiAutomaticTab, 'numeric');
            app.ImageNumberEditField_SemiTab.Limits = [0 Inf];
            app.ImageNumberEditField_SemiTab.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField_SemiTab.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditField_SemiTabValueChanged, true);
            app.UIAxes_SemiTab = uiaxes(app.SemiAutomaticTab);
            app.ShowAllLayersandSaveButton_SemiTab = uibutton(app.SemiAutomaticTab, 'push');
            app.ShowAllLayersandSaveButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersandSaveButton_SemiTabPushed, true);
            app.ShowAllLayersandSaveButton_SemiTab.Text = 'Show All Layers and Save';
            app.BoundariesPanel = uipanel(app.SemiAutomaticTab);
            app.BoundariesPanel.Title = 'Boundaries';
            app.ILMButton_SemiTab = uibutton(app.BoundariesPanel, 'push');
            app.ILMButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @ILMButton_SemiTabPushed, true);
            app.ILMButton_SemiTab.Text = 'ILM';
            app.NFLGCLButton_SemiTab = uibutton(app.BoundariesPanel, 'push');
            app.NFLGCLButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @NFLGCLButton_SemiTabPushed, true);
            app.NFLGCLButton_SemiTab.Text = 'NFL-GCL';
            app.IPLINLButton_SemiTab = uibutton(app.BoundariesPanel, 'push');
            app.IPLINLButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @IPLINLButton_SemiTabPushed, true);
            app.IPLINLButton_SemiTab.Text = 'IPL-INL';
            app.GCLIPLButton_SemiTab = uibutton(app.BoundariesPanel, 'push');
            app.GCLIPLButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @GCLIPLButton_SemiTabPushed, true);
            app.GCLIPLButton_SemiTab.Text = 'GCL-IPL';
            app.INLOPLButton_SemiTab = uibutton(app.BoundariesPanel, 'push');
            app.INLOPLButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @INLOPLButton_SemiTabPushed, true);
            app.INLOPLButton_SemiTab.Text = 'INL-OPL';
            app.OPLONLButton_SemiTab = uibutton(app.BoundariesPanel, 'push');
            app.OPLONLButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @OPLONLButton_SemiTabPushed, true);
            app.OPLONLButton_SemiTab.Text = 'OPL-ONL';
            app.ONLISOSButton_SemiTab = uibutton(app.BoundariesPanel, 'push');
            app.ONLISOSButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @ONLISOSButton_SemiTabPushed, true);
            app.ONLISOSButton_SemiTab.Text = 'ONL-IS/OS';
            app.ISOSRPEButton_SemiTab = uibutton(app.BoundariesPanel, 'push');
            app.ISOSRPEButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @ISOSRPEButton_SemiTabPushed, true);
            app.ISOSRPEButton_SemiTab.Text = 'IS/OS-RPE';
            app.BMChoroidButton_SemiTab = uibutton(app.BoundariesPanel, 'push');
            app.BMChoroidButton_SemiTab.ButtonPushedFcn = createCallbackFcn(app, @BMChoroidButton_SemiTabPushed, true);
            app.BMChoroidButton_SemiTab.Text = 'BM-Choroid';
            app.Lamp = uilamp(app.BoundariesPanel);
            app.Lamp2 = uilamp(app.BoundariesPanel);
            app.Lamp3 = uilamp(app.BoundariesPanel);
            app.Lamp4 = uilamp(app.BoundariesPanel);
            app.Lamp5 = uilamp(app.BoundariesPanel);
            app.Lamp6 = uilamp(app.BoundariesPanel);
            app.Lamp7 = uilamp(app.BoundariesPanel);
            app.Lamp9 = uilamp(app.BoundariesPanel);
            app.Lamp8 = uilamp(app.BoundariesPanel);
            app.IrregularityIndexEditFieldLabel = uilabel(app.SemiAutomaticTab);
            app.IrregularityIndexEditFieldLabel.Text = 'Irregularity Index';
            app.IrregularityIndexEditField_IPLSemiTab = uieditfield(app.SemiAutomaticTab, 'numeric');
            app.IrregularityIndexEditField_IPLSemiTab.ValueChangedFcn = createCallbackFcn(app, @IrregularityIndexEditField_IPLSemiTabValueChanged, true);
            app.IrregularityIndexEditField_2Label = uilabel(app.SemiAutomaticTab);
            app.IrregularityIndexEditField_2Label.Text = 'Irregularity Index';
            app.IrregularityIndexEditField_OPLSemiTab = uieditfield(app.SemiAutomaticTab, 'numeric');
            app.IrregularityIndexEditField_OPLSemiTab.ValueChangedFcn = createCallbackFcn(app, @IrregularityIndexEditField_OPLSemiTabValueChanged, true);
            app.SemiAutomaticLayerCorrectionTab = uitab(app.TabGroup2);
            app.SemiAutomaticLayerCorrectionTab.Title = 'Semi-Automatic Layer Correction';
            app.Panel_8 = uipanel(app.SemiAutomaticLayerCorrectionTab);
            app.ILMCheckBox_SemiCorrTab = uicheckbox(app.Panel_8);
            app.ILMCheckBox_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @ILMCheckBox_SemiCorrTabValueChanged, true);
            app.ILMCheckBox_SemiCorrTab.Text = 'ILM';
            app.NFLGCLCheckBox_SemiCorrTab = uicheckbox(app.Panel_8);
            app.NFLGCLCheckBox_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @NFLGCLCheckBox_SemiCorrTabValueChanged, true);
            app.NFLGCLCheckBox_SemiCorrTab.Text = 'NFL-GCL';
            app.GCLIPLCheckBox_SemiCorrTab = uicheckbox(app.Panel_8);
            app.GCLIPLCheckBox_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @GCLIPLCheckBox_SemiCorrTabValueChanged, true);
            app.GCLIPLCheckBox_SemiCorrTab.Text = 'GCL-IPL';
            app.IPLINLCheckBox_SemiCorrTab = uicheckbox(app.Panel_8);
            app.IPLINLCheckBox_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @IPLINLCheckBox_SemiCorrTabValueChanged, true);
            app.IPLINLCheckBox_SemiCorrTab.Text = 'IPL-INL';
            app.INLOPLCheckBox_SemiCorrTab = uicheckbox(app.Panel_8);
            app.INLOPLCheckBox_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @INLOPLCheckBox_SemiCorrTabValueChanged, true);
            app.INLOPLCheckBox_SemiCorrTab.Text = 'INL-OPL';
            app.OPLONLCheckBox_SemiCorrTab = uicheckbox(app.Panel_8);
            app.OPLONLCheckBox_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @OPLONLCheckBox_SemiCorrTabValueChanged, true);
            app.OPLONLCheckBox_SemiCorrTab.Text = 'OPL-ONL';
            app.ONLISOSCheckBox_SemiCorrTab = uicheckbox(app.Panel_8);
            app.ONLISOSCheckBox_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @ONLISOSCheckBox_SemiCorrTabValueChanged, true);
            app.ONLISOSCheckBox_SemiCorrTab.Text = 'ONL-IS/OS';
            app.ISOSRPECheckBox_SemiCorrTab = uicheckbox(app.Panel_8);
            app.ISOSRPECheckBox_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @ISOSRPECheckBox_SemiCorrTabValueChanged, true);
            app.ISOSRPECheckBox_SemiCorrTab.Text = 'IS/OS-RPE';
            app.BMChoroidCheckBox_SemiCorrTab = uicheckbox(app.Panel_8);
            app.BMChoroidCheckBox_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @BMChoroidCheckBox_SemiCorrTabValueChanged, true);
            app.BMChoroidCheckBox_SemiCorrTab.Text = 'BM-Choroid';
            app.GridEditField_3Label = uilabel(app.SemiAutomaticLayerCorrectionTab);
            app.GridEditField_3Label.Text = 'Grid ';
            app.GridEditField_SemiCorrTab = uieditfield(app.SemiAutomaticLayerCorrectionTab, 'numeric');
            app.GridEditField_SemiCorrTab.Limits = [1 50];
            app.GridEditField_SemiCorrTab.ValueDisplayFormat = '%.0f';
            app.GridEditField_SemiCorrTab.ValueChangedFcn = createCallbackFcn(app, @GridEditField_SemiCorrTabValueChanged, true);
            app.GridEditField_SemiCorrTab.Value = 10;
            app.ShowAllLayersandSaveButton_SemiCorrTab = uibutton(app.SemiAutomaticLayerCorrectionTab, 'push');
            app.ShowAllLayersandSaveButton_SemiCorrTab.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersandSaveButton_SemiCorrTabPushed, true);
            app.ShowAllLayersandSaveButton_SemiCorrTab.Text = 'Show All Layers and Save';
            app.UIAxes_SemiCorrTab = uiaxes(app.SemiAutomaticLayerCorrectionTab);
            app.ManualGridTab = uitab(app.TabGroup2);
            app.ManualGridTab.Title = 'Manual-Grid';
            app.GridEditFieldLabel = uilabel(app.ManualGridTab);
            app.GridEditFieldLabel.Text = 'Grid ';
            app.GridEditField_GridTab = uieditfield(app.ManualGridTab, 'numeric');
            app.GridEditField_GridTab.Limits = [1 50];
            app.GridEditField_GridTab.ValueChangedFcn = createCallbackFcn(app, @GridEditField_GridTabValueChanged, true);
            app.GridEditField_GridTab.Value = 10;
            app.Panel = uipanel(app.ManualGridTab);
            app.ILMCheckBox_GridTab = uicheckbox(app.Panel);
            app.ILMCheckBox_GridTab.ValueChangedFcn = createCallbackFcn(app, @ILMCheckBox_GridTabValueChanged, true);
            app.ILMCheckBox_GridTab.Text = 'ILM';
            app.NFLGCLCheckBox_GridTab = uicheckbox(app.Panel);
            app.NFLGCLCheckBox_GridTab.ValueChangedFcn = createCallbackFcn(app, @NFLGCLCheckBox_GridTabValueChanged, true);
            app.NFLGCLCheckBox_GridTab.Text = 'NFL-GCL';
            app.GCLIPLCheckBox_GridTab = uicheckbox(app.Panel);
            app.GCLIPLCheckBox_GridTab.ValueChangedFcn = createCallbackFcn(app, @GCLIPLCheckBox_GridTabValueChanged, true);
            app.GCLIPLCheckBox_GridTab.Text = 'GCL-IPL';
            app.IPLINLCheckBox_GridTab = uicheckbox(app.Panel);
            app.IPLINLCheckBox_GridTab.ValueChangedFcn = createCallbackFcn(app, @IPLINLCheckBox_GridTabValueChanged, true);
            app.IPLINLCheckBox_GridTab.Text = 'IPL-INL';
            app.INLOPLCheckBox_GridTab = uicheckbox(app.Panel);
            app.INLOPLCheckBox_GridTab.ValueChangedFcn = createCallbackFcn(app, @INLOPLCheckBox_GridTabValueChanged, true);
            app.INLOPLCheckBox_GridTab.Text = 'INL-OPL';
            app.OPLONLCheckBox_GridTab = uicheckbox(app.Panel);
            app.OPLONLCheckBox_GridTab.ValueChangedFcn = createCallbackFcn(app, @OPLONLCheckBox_GridTabValueChanged, true);
            app.OPLONLCheckBox_GridTab.Text = 'OPL-ONL';
            app.ONLISOSCheckBox_GridTab = uicheckbox(app.Panel);
            app.ONLISOSCheckBox_GridTab.ValueChangedFcn = createCallbackFcn(app, @ONLISOSCheckBox_GridTabValueChanged, true);
            app.ONLISOSCheckBox_GridTab.Text = 'ONL-IS/OS';
            app.ISOSRPECheckBox_GridTab = uicheckbox(app.Panel);
            app.ISOSRPECheckBox_GridTab.ValueChangedFcn = createCallbackFcn(app, @ISOSRPECheckBox_GridTabValueChanged, true);
            app.ISOSRPECheckBox_GridTab.Text = 'IS/OS-RPE';
            app.BMChoroidCheckBox_GridTab = uicheckbox(app.Panel);
            app.BMChoroidCheckBox_GridTab.ValueChangedFcn = createCallbackFcn(app, @BMChoroidCheckBox_GridTabValueChanged, true);
            app.BMChoroidCheckBox_GridTab.Text = 'BM-Choroid';
            app.ImageNumberEditField_2Label = uilabel(app.ManualGridTab);
            app.ImageNumberEditField_2Label.Text = 'Image Number';
            app.ImageNumberEditField_GridTab = uieditfield(app.ManualGridTab, 'numeric');
            app.ImageNumberEditField_GridTab.Limits = [0 Inf];
            app.ImageNumberEditField_GridTab.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField_GridTab.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditField_GridTabValueChanged, true);
            app.ShowAllLayersandSaveButton_GridTab = uibutton(app.ManualGridTab, 'push');
            app.ShowAllLayersandSaveButton_GridTab.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersandSaveButton_GridTabPushed, true);
            app.ShowAllLayersandSaveButton_GridTab.Text = 'Show All Layers and Save';
            app.UIAxes_GridTab = uiaxes(app.ManualGridTab);
            app.ManualGridLayerCorrectionTab = uitab(app.TabGroup2);
            app.ManualGridLayerCorrectionTab.Title = 'Manual-Grid Layer Correction';
            app.Panel_4 = uipanel(app.ManualGridLayerCorrectionTab);
            app.ILMCheckBox_GridCorrTab = uicheckbox(app.Panel_4);
            app.ILMCheckBox_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @ILMCheckBox_GridCorrTabValueChanged, true);
            app.ILMCheckBox_GridCorrTab.Text = 'ILM';
            app.NFLGCLCheckBox_GridCorrTab = uicheckbox(app.Panel_4);
            app.NFLGCLCheckBox_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @NFLGCLCheckBox_GridCorrTabValueChanged, true);
            app.NFLGCLCheckBox_GridCorrTab.Text = 'NFL-GCL';
            app.GCLIPLCheckBox_GridCorrTab = uicheckbox(app.Panel_4);
            app.GCLIPLCheckBox_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @GCLIPLCheckBox_GridCorrTabValueChanged, true);
            app.GCLIPLCheckBox_GridCorrTab.Text = 'GCL-IPL';
            app.IPLINLCheckBox_GridCorrTab = uicheckbox(app.Panel_4);
            app.IPLINLCheckBox_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @IPLINLCheckBox_GridCorrTabValueChanged, true);
            app.IPLINLCheckBox_GridCorrTab.Text = 'IPL-INL';
            app.INLOPLCheckBox_GridCorrTab = uicheckbox(app.Panel_4);
            app.INLOPLCheckBox_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @INLOPLCheckBox_GridCorrTabValueChanged, true);
            app.INLOPLCheckBox_GridCorrTab.Text = 'INL-OPL';
            app.OPLONLCheckBox_GridCorrTab = uicheckbox(app.Panel_4);
            app.OPLONLCheckBox_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @OPLONLCheckBox_GridCorrTabValueChanged, true);
            app.OPLONLCheckBox_GridCorrTab.Text = 'OPL-ONL';
            app.ONLISOSCheckBox_GridCorrTab = uicheckbox(app.Panel_4);
            app.ONLISOSCheckBox_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @ONLISOSCheckBox_GridCorrTabValueChanged, true);
            app.ONLISOSCheckBox_GridCorrTab.Text = 'ONL-IS/OS';
            app.ISOSRPECheckBox_GridCorrTab = uicheckbox(app.Panel_4);
            app.ISOSRPECheckBox_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @ISOSRPECheckBox_GridCorrTabValueChanged, true);
            app.ISOSRPECheckBox_GridCorrTab.Text = 'IS/OS-RPE';
            app.BMChoroidCheckBox_GridCorrTab = uicheckbox(app.Panel_4);
            app.BMChoroidCheckBox_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @BMChoroidCheckBox_GridCorrTabValueChanged, true);
            app.BMChoroidCheckBox_GridCorrTab.Text = 'BM-Choroid';
            app.GridEditField_2Label = uilabel(app.ManualGridLayerCorrectionTab);
            app.GridEditField_2Label.Text = 'Grid ';
            app.GridEditField_GridCorrTab = uieditfield(app.ManualGridLayerCorrectionTab, 'numeric');
            app.GridEditField_GridCorrTab.Limits = [1 50];
            app.GridEditField_GridCorrTab.ValueDisplayFormat = '%.0f';
            app.GridEditField_GridCorrTab.ValueChangedFcn = createCallbackFcn(app, @GridEditField_GridCorrTabValueChanged, true);
            app.GridEditField_GridCorrTab.Value = 10;
            app.ShowAllLayersandSaveButton_GridCorrTab = uibutton(app.ManualGridLayerCorrectionTab, 'push');
            app.ShowAllLayersandSaveButton_GridCorrTab.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersandSaveButton_GridCorrTabPushed, true);
            app.ShowAllLayersandSaveButton_GridCorrTab.Text = 'Show All Layers and Save';
            app.UIAxes_GridCorrTab = uiaxes(app.ManualGridLayerCorrectionTab);
            app.FluidSegmentationTab = uitab(app.TabGroup);
            app.FluidSegmentationTab.Title = 'Fluid Segmentation';
            app.TabGroup3 = uitabgroup(app.FluidSegmentationTab);
            app.SemiAutomaticTab_2 = uitab(app.TabGroup3);
            app.SemiAutomaticTab_2.Title = 'Semi-Automatic';
            app.UIAxes_SemiFluidTab = uiaxes(app.SemiAutomaticTab_2);
            app.Panel_5 = uipanel(app.SemiAutomaticTab_2);
            app.IRFCheckBox_SemiFluidTab = uicheckbox(app.Panel_5);
            app.IRFCheckBox_SemiFluidTab.ValueChangedFcn = createCallbackFcn(app, @IRFCheckBox_SemiFluidTabValueChanged, true);
            app.IRFCheckBox_SemiFluidTab.Text = 'IRF';
            app.SRFCheckBox_SemiFluidTab = uicheckbox(app.Panel_5);
            app.SRFCheckBox_SemiFluidTab.ValueChangedFcn = createCallbackFcn(app, @SRFCheckBox_SemiFluidTabValueChanged, true);
            app.SRFCheckBox_SemiFluidTab.Text = 'SRF';
            app.PEDCheckBox_SemiFluidTab = uicheckbox(app.Panel_5);
            app.PEDCheckBox_SemiFluidTab.ValueChangedFcn = createCallbackFcn(app, @PEDCheckBox_SemiFluidTabValueChanged, true);
            app.PEDCheckBox_SemiFluidTab.Text = 'PED';
            app.DeleteButton_SemiFluidTab = uibutton(app.Panel_5, 'push');
            app.DeleteButton_SemiFluidTab.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_SemiFluidTabPushed, true);
            app.DeleteButton_SemiFluidTab.Text = 'Delete';
            app.FinishButton_SemiFluidTab = uibutton(app.Panel_5, 'push');
            app.FinishButton_SemiFluidTab.ButtonPushedFcn = createCallbackFcn(app, @FinishButton_SemiFluidTabPushed, true);
            app.FinishButton_SemiFluidTab.Text = 'Finish';
            app.ImageNumberEditField_3Label = uilabel(app.Panel_5);
            app.ImageNumberEditField_3Label.Text = 'Image Number';
            app.ImageNumberEditField_SemiFluidTab = uieditfield(app.Panel_5, 'numeric');
            app.ImageNumberEditField_SemiFluidTab.Limits = [0 Inf];
            app.ImageNumberEditField_SemiFluidTab.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField_SemiFluidTab.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditField_SemiFluidTabValueChanged, true);
            app.NumberofObjectsEditFieldLabel = uilabel(app.Panel_5);
            app.NumberofObjectsEditFieldLabel.Text = 'Number of Objects';
            app.NumberofObjectsEditField_SemiFluidTab = uieditfield(app.Panel_5, 'numeric');
            app.NumberofObjectsEditField_SemiFluidTab.Limits = [0 Inf];
            app.NumberofObjectsEditField_SemiFluidTab.ValueDisplayFormat = '%.0f';
            app.NumberofObjectsEditField_SemiFluidTab.ValueChangedFcn = createCallbackFcn(app, @NumberofObjectsEditField_SemiFluidTabValueChanged, true);
            app.ManualTab = uitab(app.TabGroup3);
            app.ManualTab.Title = 'Manual';
            app.Panel_6 = uipanel(app.ManualTab);
            app.IRFCheckBox_ManualFluidTab = uicheckbox(app.Panel_6);
            app.IRFCheckBox_ManualFluidTab.ValueChangedFcn = createCallbackFcn(app, @IRFCheckBox_ManualFluidTabValueChanged, true);
            app.IRFCheckBox_ManualFluidTab.Text = 'IRF';
            app.SRFCheckBox_ManualFluidTab = uicheckbox(app.Panel_6);
            app.SRFCheckBox_ManualFluidTab.ValueChangedFcn = createCallbackFcn(app, @SRFCheckBox_ManualFluidTabValueChanged, true);
            app.SRFCheckBox_ManualFluidTab.Text = 'SRF';
            app.PEDCheckBox_ManualFluidTab = uicheckbox(app.Panel_6);
            app.PEDCheckBox_ManualFluidTab.ValueChangedFcn = createCallbackFcn(app, @PEDCheckBox_ManualFluidTabValueChanged, true);
            app.PEDCheckBox_ManualFluidTab.Text = 'PED';
            app.DeleteButton_ManualFluidTab = uibutton(app.Panel_6, 'push');
            app.DeleteButton_ManualFluidTab.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_ManualFluidTabPushed, true);
            app.DeleteButton_ManualFluidTab.Text = 'Delete';
            app.FinishButton_ManualFluidTab = uibutton(app.Panel_6, 'push');
            app.FinishButton_ManualFluidTab.ButtonPushedFcn = createCallbackFcn(app, @FinishButton_ManualFluidTabPushed, true);
            app.FinishButton_ManualFluidTab.Text = 'Finish';
            app.ImageNumberEditField_4Label = uilabel(app.Panel_6);
            app.ImageNumberEditField_4Label.Text = 'Image Number';
            app.ImageNumberEditField_ManualFluidTab = uieditfield(app.Panel_6, 'numeric');
            app.ImageNumberEditField_ManualFluidTab.Limits = [0 Inf];
            app.ImageNumberEditField_ManualFluidTab.ValueDisplayFormat = '%.0f';
            app.ImageNumberEditField_ManualFluidTab.ValueChangedFcn = createCallbackFcn(app, @ImageNumberEditField_ManualFluidTabValueChanged, true);
            app.NumberofObjectsEditField_2Label = uilabel(app.Panel_6);
            app.NumberofObjectsEditField_2Label.Text = 'Number of Objects';
            app.NumberofObjectsEditField_ManualFluidTab = uieditfield(app.Panel_6, 'numeric');
            app.NumberofObjectsEditField_ManualFluidTab.Limits = [0 Inf];
            app.NumberofObjectsEditField_ManualFluidTab.ValueDisplayFormat = '%.0f';
            app.NumberofObjectsEditField_ManualFluidTab.ValueChangedFcn = createCallbackFcn(app, @NumberofObjectsEditField_ManualFluidTabValueChanged, true);
            app.UIAxes_ManualFluidTab = uiaxes(app.ManualTab);
            app.PeripapillaryTab = uitab(app.TabGroup);
            app.PeripapillaryTab.Title = 'Peripapillary';
            app.UIAxes_Peri = uiaxes(app.PeripapillaryTab);
            app.Panel_7 = uipanel(app.PeripapillaryTab);
            app.Boundary6Button_Peri = uibutton(app.Panel_7, 'push');
            app.Boundary6Button_Peri.ButtonPushedFcn = createCallbackFcn(app, @Boundary6Button_PeriPushed, true);
            app.Boundary6Button_Peri.Text = 'Boundary 6';
            app.Boundary3Button_Peri = uibutton(app.Panel_7, 'push');
            app.Boundary3Button_Peri.ButtonPushedFcn = createCallbackFcn(app, @Boundary3Button_PeriPushed, true);
            app.Boundary3Button_Peri.Text = 'Boundary 3';
            app.VeinDetectionButton_Peri = uibutton(app.Panel_7, 'push');
            app.VeinDetectionButton_Peri.ButtonPushedFcn = createCallbackFcn(app, @VeinDetectionButton_PeriPushed, true);
            app.VeinDetectionButton_Peri.Text = 'Vein Detection';
            app.Boundary2Button_Peri = uibutton(app.Panel_7, 'push');
            app.Boundary2Button_Peri.ButtonPushedFcn = createCallbackFcn(app, @Boundary2Button_PeriPushed, true);
            app.Boundary2Button_Peri.Text = 'Boundary 2';
            app.Boundary4Button_Peri = uibutton(app.Panel_7, 'push');
            app.Boundary4Button_Peri.ButtonPushedFcn = createCallbackFcn(app, @Boundary4Button_PeriPushed, true);
            app.Boundary4Button_Peri.Text = 'Boundary 4';
            app.Boundary3_finalButton_Peri = uibutton(app.Panel_7, 'push');
            app.Boundary3_finalButton_Peri.ButtonPushedFcn = createCallbackFcn(app, @Boundary3_finalButton_PeriPushed, true);
            app.Boundary3_finalButton_Peri.Text = 'Boundary 3_final';
            app.Boundary5Button_Peri = uibutton(app.Panel_7, 'push');
            app.Boundary5Button_Peri.ButtonPushedFcn = createCallbackFcn(app, @Boundary5Button_PeriPushed, true);
            app.Boundary5Button_Peri.Text = 'Boundary 5';
            app.Boundary7Button_Peri = uibutton(app.Panel_7, 'push');
            app.Boundary7Button_Peri.ButtonPushedFcn = createCallbackFcn(app, @Boundary7Button_PeriPushed, true);
            app.Boundary7Button_Peri.Text = 'Boundary 7';
            app.Boundary8Button_Peri = uibutton(app.Panel_7, 'push');
            app.Boundary8Button_Peri.ButtonPushedFcn = createCallbackFcn(app, @Boundary8Button_PeriPushed, true);
            app.Boundary8Button_Peri.Text = 'Boundary 8';
            app.Boundary1Button_Peri = uibutton(app.Panel_7, 'push');
            app.Boundary1Button_Peri.ButtonPushedFcn = createCallbackFcn(app, @Boundary1Button_PeriPushed, true);
            app.Boundary1Button_Peri.Text = 'Boundary 1';
            app.LoadButton_Peri = uibutton(app.Panel_7, 'push');
            app.LoadButton_Peri.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_PeriPushed, true);
            app.LoadButton_Peri.Text = 'Load';
            app.ShowAllLayersandSaveButton_Peri = uibutton(app.PeripapillaryTab, 'push');
            app.ShowAllLayersandSaveButton_Peri.ButtonPushedFcn = createCallbackFcn(app, @ShowAllLayersandSaveButton_PeriPushed, true);
            app.ShowAllLayersandSaveButton_Peri.Text = 'Show All Layers and Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end